% description: Find objects (e.g. synapses) in three-dimentional image stacks.
% Copyright 2016-2018 Luca Della Santina (luca.dellasantina@gmail.com)
classdef ObjectFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Lamp                           matlab.ui.control.Lamp
        TabGroup                       matlab.ui.container.TabGroup
        DetectTab                      matlab.ui.container.Tab
        FolderButton                   matlab.ui.control.Button
        LoadImagesButton               matlab.ui.control.Button
        FindObjectsButton              matlab.ui.control.Button
        FilterButton                   matlab.ui.control.Button
        ValidateButton                 matlab.ui.control.Button
        SearchalgorithmsettingsPanel   matlab.ui.container.Panel
        MaximumobjectdiametermXYLabel  matlab.ui.control.Label
        MaxSizeXY                      matlab.ui.control.NumericEditField
        ZLabel                         matlab.ui.control.Label
        MaxSizeZ                       matlab.ui.control.NumericEditField
        ZLabel_2                       matlab.ui.control.Label
        MinSizeZ                       matlab.ui.control.NumericEditField
        Watershed                      matlab.ui.control.CheckBox
        MinimumobjectdiametermXYLabel  matlab.ui.control.Label
        MinSizeXY                      matlab.ui.control.NumericEditField
        Sphericity                     matlab.ui.control.CheckBox
        Scheduler                      matlab.ui.control.CheckBox
        ScheduledTime                  matlab.ui.control.Knob
        FiltersettingsexcludeobjectsifPanel  matlab.ui.container.Panel
        EdgeDotCut                     matlab.ui.control.CheckBox
        SingleZDotCut                  matlab.ui.control.CheckBox
        xyStableDots                   matlab.ui.control.CheckBox
        ActivitylogTextAreaLabel       matlab.ui.control.Label
        Log                            matlab.ui.control.TextArea
        ValidationsettingsPanel        matlab.ui.container.Panel
        Passing                        matlab.ui.control.CheckBox
        NonPassing                     matlab.ui.control.CheckBox
        ValidationmethodLabel          matlab.ui.control.Label
        InspectorType                  matlab.ui.control.DropDown
        PlotTab                        matlab.ui.container.Tab
        DensitybydepthButton           matlab.ui.control.Button
        LocationsButton                matlab.ui.control.Button
        HeatmapButton                  matlab.ui.control.Button
        DensitylinearButton            matlab.ui.control.Button
        SkeletonButton                 matlab.ui.control.Button
        ShollAnalysisButton            matlab.ui.control.Button
        PlotNewWinCheckBox             matlab.ui.control.CheckBox
        ExportDataCheckBox             matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        AutomateTab                    matlab.ui.container.Tab
        BatchSearchSettings            matlab.ui.container.Panel
        MaxSizeXY_2Label               matlab.ui.control.Label
        BatchMaxSizeXY                 matlab.ui.control.NumericEditField
        MaxSizeZ_2Label                matlab.ui.control.Label
        BatchMaxSizeZ                  matlab.ui.control.NumericEditField
        MinSizeZ_2Label                matlab.ui.control.Label
        BatchMinSizeZ                  matlab.ui.control.NumericEditField
        BatchWatershed                 matlab.ui.control.CheckBox
        MinSizeXY_2Label               matlab.ui.control.Label
        BatchMinSizeXY                 matlab.ui.control.NumericEditField
        BatchSphericity                matlab.ui.control.CheckBox
        BatchFilterSettings            matlab.ui.container.Panel
        BatchEdgeDotCut                matlab.ui.control.CheckBox
        BatchSingleZDotCut             matlab.ui.control.CheckBox
        BatchxyStableDots              matlab.ui.control.CheckBox
        ThresholdingparameterLabel     matlab.ui.control.Label
        BatchThreshParam               matlab.ui.control.DropDown
        CutoffvalueLabel               matlab.ui.control.Label
        BatchThreshValue               matlab.ui.control.NumericEditField
        ProcessinglistPanel            matlab.ui.container.Panel
        lstBatch                       matlab.ui.control.ListBox
        btnAdd                         matlab.ui.control.Button
        btnRemove                      matlab.ui.control.Button
        btnUp                          matlab.ui.control.Button
        btnDown                        matlab.ui.control.Button
        btnTop                         matlab.ui.control.Button
        btnBottom                      matlab.ui.control.Button
        btnClear                       matlab.ui.control.Button
        btnBatchStart                  matlab.ui.control.Button
        BatchLog                       matlab.ui.control.TextArea
        BatchScheduledTime             matlab.ui.control.Knob
        BatchScheduler                 matlab.ui.control.CheckBox
        btnBatchStop                   matlab.ui.control.StateButton
        ColocalizeTab                  matlab.ui.container.Tab
        ChannelsavailableforcolocalizionanalysisPanel  matlab.ui.container.Panel
        lstColo                        matlab.ui.control.ListBox
        btnColocalize                  matlab.ui.control.Button
        btnColocalize2                 matlab.ui.control.Button
        ProcessedchannelsPanel         matlab.ui.container.Panel
        lstColoDone                    matlab.ui.control.ListBox
        btnColoDoneRemove              matlab.ui.control.Button
        btnColoDoneClear               matlab.ui.control.Button
        btnColoRedo                    matlab.ui.control.Button
        lblColocNumObjects             matlab.ui.control.Label
        lblColocRate                   matlab.ui.control.Label
        btnColoDoneRefresh             matlab.ui.control.Button
        lblReferenceName               matlab.ui.control.Label
        lblColoFishName                matlab.ui.control.Label
        btnColoDouble                  matlab.ui.control.Button
        btnColoDoneUp                  matlab.ui.control.Button
        btnColoDoneDown                matlab.ui.control.Button
        lblColo2FishName               matlab.ui.control.Label
        lblColoc2Rate                  matlab.ui.control.Label
        lblColoc2NumObjects            matlab.ui.control.Label
        lblColocRateDouble             matlab.ui.control.Label
        AboutTab                       matlab.ui.container.Tab
        lblObjectFinder                matlab.ui.control.Label
        lblLicense                     matlab.ui.control.Label
        lblCopyright                   matlab.ui.control.Label
        btnAppIcon                     matlab.ui.control.Button
        lblVersion                     matlab.ui.control.Label
        lblWebsite                     matlab.ui.control.Label
        txtLicense                     matlab.ui.control.TextArea
        FolderField                    matlab.ui.control.EditField
    end

%% ObjectFinder - Recognize 3D structures in image stacks
%  Copyright (C) 2016,2017,2018 Luca Della Santina
%
%  This file is part of ObjectFinder.
%
%  ObjectFinder is free software: you can redistribute it and/or modify
%  it under the terms of the GNU General Public License as published by
%  the Free Software Foundation, either version 3 of the License, or
%  (at your option) any later version.
%
%  This program is distributed in the hope that it will be useful,
%  but WITHOUT ANY WARRANTY; without even the implied warranty of
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%  GNU General Public License for more details.
%
%  You should have received a copy of the GNU General Public License
%  along with this program.  If not, see <https://www.gnu.org/licenses/>.
%
    properties (Access = private)
        Settings
        Post
        Dend
        Mask
        Dots
        Filter
        Skel
        SkelFiner
        Coloc
        Density
        TimerDelay
        Timer
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Timer = timer;
            colormap(app.UIAxes, gray(256));
            xticks(app.UIAxes, []);
            yticks(app.UIAxes, []);
            box(app.UIAxes, 'on');
            
            if ~isdir([pwd filesep 'I'])
                app.Lamp.Color = 'white';
                app.FolderField.Value = 'Select a valid working folder using the first button';
                app.Log.Value = 'Select a valid working folder using the first button';
                app.LoadImagesButton.Enable = 'off';
                app.FindObjectsButton.Enable = 'off';
                app.FilterButton.Enable = 'off';
                app.ValidateButton.Enable = 'off';
            else
                app.Log.Value = {'Working folder:'; pwd};
                app.LoadImagesButton.Enable = 'on';
                app.Lamp.Color = 'green';
                app.FolderField.Value = pwd;
                app.Settings.TPN = [pwd filesep];
            
                % Populate list of channels for colocalization
                app.lstColo.Items = {}; 
                fileList = dir([app.Settings.TPN 'I' filesep '*.tif']);
                for i=1:numel(fileList)
                    if isempty(app.lstColo.Items)
                        app.lstColo.Items = {fileList(i).name};
                    else
                        app.lstColo.Items{end+1} = fileList(i).name;
                    end                    
                end
                app.btnColoDoneRefreshButtonPushed;
                
                if exist([pwd filesep 'Settings.mat'], 'file')
                    load([pwd filesep 'Settings.mat']);
                    app.Settings = Settings;
                    app.Settings.TPN = [pwd filesep]; % update current working folder in Settings.mat
                    Settings = app.Settings;
                    save([app.Settings.TPN 'Settings.mat'], 'Settings');
                end
                if exist([pwd filesep 'Post.mat'], 'file')
                    load([pwd filesep 'Post.mat']);
                    app.Post = Post;
                end
                if exist([pwd filesep 'Mask.mat'], 'file')
                    load([pwd filesep 'Mask.mat']);
                    app.Mask = Mask;
                end
                if exist([pwd filesep 'Dend.mat'], 'file')
                    load([pwd filesep 'Dend.mat']);
                    app.Dend = Dend;
                end
                if exist([pwd filesep 'Dots.mat'], 'file')
                    load([pwd filesep 'Dots.mat']);
                    app.Dots = Dots;
                    app.FindObjectsButton.Enable = 'on';
                    app.FilterButton.Enable = 'on';
                end
                if exist([pwd filesep 'Filter.mat'], 'file')
                    load([pwd filesep 'Filter.mat']);
                    app.Filter = Filter;
                    app.DensitybydepthButton.Enable = 'on';
                    app.ValidateButton.Enable = 'on';                    
                else
                    app.DensitybydepthButton.Enable = 'off';
                end  
                if exist([pwd filesep 'Skel.mat'], 'file')
                    load([pwd filesep 'Skel.mat']);
                    app.Skel = Skel;
                    app.LocationsButton.Enable = 'on';
                    app.DensitylinearButton.Enable = 'on';
                    app.HeatmapButton.Enable = 'on';
                    app.SkeletonButton.Enable = 'on';
                    app.ShollAnalysisButton.Enable = 'on';
                else
                    app.LocationsButton.Enable = 'off';
                    app.DensitylinearButton.Enable = 'off';
                    app.HeatmapButton.Enable = 'off';
                    app.SkeletonButton.Enable = 'off';
                    app.ShollAnalysisButton.Enable = 'off';
                end            
            end
            
            % Clear plotting area
            cla(app.UIAxes)
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            box(app.UIAxes, 'on');
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            cla(app.UIAxes);
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            box(app.UIAxes, 'on');            
            
            app.Settings = struct;
            app.Settings.TPN = [pwd filesep];
            if ~isdir([app.Settings.TPN 'data']), mkdir([app.Settings.TPN 'data']); end
            if ~isdir([app.Settings.TPN 'images']), mkdir([app.Settings.TPN 'images']); end
            
            % Read images to be used for analysis
            tmpDir=[app.Settings.TPN 'I' filesep];
            tmpFiles=dir([tmpDir '*.tif']);
            
            % Get dimensions of the first 3D TIF image (all other imagest must be same)
            tmpImInfo = imfinfo([tmpDir tmpFiles(1).name]);
            zs = numel(tmpImInfo); xs = tmpImInfo.Width; ys = tmpImInfo.Height;
            
            % Retrieve XY and Z resolution from TIFF image descriptor
            tmpXYres = num2str(1/tmpImInfo(1).XResolution);
            tmpZres = '0.3';% Default Z resolution if not specified in the TIF image
            if contains(tmpImInfo(1).ImageDescription, 'spacing=')
                tmpPos = strfind(tmpImInfo(1).ImageDescription,'spacing=');
                tmpZres = tmpImInfo(1).ImageDescription(tmpPos+8:end);
                tmpZres = regexp(tmpZres,'\n','split');
                tmpZres = tmpZres{1};
            end
            
            % Read all TIF images into a single Iraw matrix (X,Y,Z,Imge#)
            Iraw=zeros(ys, xs, zs, numel(tmpFiles), 'uint8');
            txtBar('Loading image stacks... ');
            app.Log.Value{end+1} = 'Loading image stacks... ';
            
            for i = 1:numel(tmpFiles)
                for j = 1:zs
                    Iraw(:,:,j,i)=imread([tmpDir tmpFiles(i).name], j);
                    txtBar( 100*(j+i*zs-zs)/(zs*numel(tmpFiles)) );
                end
            end
            txtBar('DONE');
            app.Log.Value{end} = [app.Log.Value{end} 'DONE'];
            
            Imax=squeeze(max(Iraw,[],3)); % Create a MIP of each image to display
            h=figure('units', 'normalized', 'position', [0.05 0.25 0.9 0.4]);
            for i = 1:size(Imax,3)
                subplot(1,size(Imax,3),i)
                image(Imax(:,:,i)*(500/double(max(max(Imax(:,:,i))))))
                title(['# ' num2str(i) ': ' tmpFiles(i).name]);
                set(gca,'box','off', 'YTickLabel',[],'XTickLabel',[]);
            end
            colormap gray(256);
            
            % Ask user for image idendity settings
            tmpPrompt = {'Objects channel #:',...
                'Mask channel (0:no/use external Mask.mat):',...
                'xy resolution :',...
                'z resolution :',...
                'Debug mode (0:no, 1:yes):'};
            tmpAns = inputdlg(tmpPrompt, 'Assign channels', 1,...
                {'3', '0', tmpXYres, tmpZres,'0'});
            
            app.Settings.ImInfo.xNumVox = xs;
            app.Settings.ImInfo.yNumVox = ys;
            app.Settings.ImInfo.zNumVox = zs;
            app.Settings.ImInfo.PostCh  = str2double(tmpAns(1));
            app.Settings.ImInfo.MaskCh  = str2double(tmpAns(2));
            app.Settings.ImInfo.xyum    = str2double(tmpAns(3));
            app.Settings.ImInfo.zum     = str2double(tmpAns(4));
            app.Settings.debug          = str2double(tmpAns(5));
            
            % Write Channels into matlab files
            app.Log.Value{end+1} = 'Saving selected channels... ';
            
            if app.Settings.ImInfo.PostCh
                Post=Iraw(:,:,:,app.Settings.ImInfo.PostCh);
                save([app.Settings.TPN 'Post.mat'], 'Post');
                app.Post = Post;
            end
            
            if app.Settings.ImInfo.MaskCh
                Mask = Iraw(:,:,:,app.Settings.ImInfo.MaskCh);
                Mask = Mask / max(max(max(Mask))); % Normalize mask max value to 1
                save([app.Settings.TPN 'Mask.mat'], 'Mask');
                saveastiff(app.Post, [app.Settings.TPN 'images' filesep 'PostMask.tif']); %save 3-D tiff image of the masked Post
                app.Mask = Mask;
            elseif exist([app.Settings.TPN 'Mask.mat'], 'file')
                disp('Loading Mask from Mask.mat');
                load([app.Settings.TPN 'Mask.mat']);
                app.Mask = Mask;
            else
                % Create dummy mask with all ones to process the entire image
                Mask = ones(size(app.Post), 'uint8');
                save([app.Settings.TPN 'Mask.mat'], 'Mask');
                app.Mask = Mask;
            end
            app.Log.Value{end} = [app.Log.Value{end} 'DONE'];
      
            close all; clear i j Iraw Imax Is tmp* xs ys zs ans;
            
            Settings = app.Settings;
            save([app.Settings.TPN 'Settings.mat'], 'Settings');
            
            app.FindObjectsButton.Enable = 'on';
            app.Lamp.Color = 'green';
        end

        % Button pushed function: FindObjectsButton
        function FindObjectsButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Calculate volume of the minimum / maximum object sizes allowed, assuming they are spheres
            % largest CtBP2 = elipsoid 1um-xy/2um-z diameter = ~330 voxels (.103x.103x0.3 um pixel size)
            MaxDotSize = round((4/3*pi*(app.MaxSizeXY.Value/2)*(app.MaxSizeXY.Value/2)*(app.MaxSizeZ.Value/2)) / (app.Settings.ImInfo.xyum*app.Settings.ImInfo.xyum*app.Settings.ImInfo.zum));
            MinDotSize = round((4/3*pi*(app.MinSizeXY.Value/2)*(app.MinSizeXY.Value/2)*(app.MinSizeZ.Value/2)) / (app.Settings.ImInfo.xyum*app.Settings.ImInfo.xyum*app.Settings.ImInfo.zum));
            
            app.Settings.objfinder.blockBuffer= round(app.MaxSizeXY.Value/app.Settings.ImInfo.xyum);  % Overlapping buffer region between search block should be as big as the biggest dot we want to measure to make sure we are not missing any.
            app.Settings.objfinder.blockSize = max(64, 3*app.Settings.objfinder.blockBuffer); % Use 64 (best for PC with 16Gb ram) or 3x blockBuffer (maximum speed), whichever bigger
            app.Settings.objfinder.thresholdStep = 2;              % stepping when iteratively looping through pixel intensities for connected components
            app.Settings.objfinder.maxDotSize = MaxDotSize;        % max dot size exclusion criteria for single-peak dot DURING ITERATIVE THRESHOLDING, NOT FINAL.
            app.Settings.objfinder.minDotSize= MinDotSize;         % min dot size exclusion criteria DURING ITERATIVE THRESHOLDING, NOT FINAL.
            app.Settings.objfinder.itMin = 2;                      % Minimum iterative threshold allowed to be analyzed as voxels belonging to any dot...filter to remove value '1' pass thresholds. value '2' is also mostly noise for PSD95 dots, so 3 is the good starting point HO 2/9/2011
            app.Settings.objfinder.minFinalDotITMax = 3;           % minimum ITMax allowed as FINAL dots. Any found dot whose ITMax is below this threshold value is removed from the registration into Dots. 5 will be the best for PSD95. HO 1/5/2010
            app.Settings.objfinder.watershed = app.Watershed.Value;% False if you want to skip watershed segmentation during object search
            
            Settings = app.Settings;
            save([app.Settings.TPN 'Settings.mat'], 'Settings');            
            
            if app.Scheduler.Value                
                if app.ScheduledTime.Value > hour(datetime)
                    app.TimerDelay = round(app.ScheduledTime.Value*60 - hour(datetime)*60 - minute(datetime)); % Custom time is today
                else
                    app.TimerDelay = round(app.ScheduledTime.Value+24)*60 - hour(datetime)*60 - minute(datetime); %Custom time is tomorrow
                end
                app.Log.Value{end+1} = ['Waiting ' num2str(app.TimerDelay) ' minutes, then start searching for objects... '];
                app.Timer = timer('TimerFcn',@(~,~)disp('Time is up.'),'StartDelay',app.TimerDelay*60);
                start(app.Timer);
                wait(app.Timer);
                delete(app.Timer);
                app.Log.Value{end} = [app.Log.Value{end} 'Timer is up! starting analysis.'];;
            end
            
            app.Log.Value{end+1} = 'Searching objects in the volume (please wait)... ';
            % Seach objects inside the masked volume
            app.Dots = findObjects(app.Post.*app.Mask, app.Settings);
            app.Log.Value{end} = [app.Log.Value{end} 'DONE'];
            app.Log.Value{end+1} = 'Calculating sphericity of objects... ';
            % Create fields about sphericity of objects (Rounding)
            if app.Sphericity.Value, app.Dots = fitSphere(app.Dots, app.Settings); end
            app.Log.Value{end} = [app.Log.Value{end} 'DONE'];
            
            Dots= app.Dots;
            save([app.Settings.TPN 'Dots.mat'],'Dots');
            Settings = app.Settings;
            save([app.Settings.TPN 'Settings.mat'],'Settings');
            
            app.FilterButton.Enable = 'on';
            app.Lamp.Color = 'green';
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            % Filter objects according to the following post-processing criteria (SG)
            app.Lamp.Color = 'yellow'; pause(0.2);

            Options.EdgeDotCut               = app.EdgeDotCut.Value;
            Options.SingleZDotCut            = app.SingleZDotCut.Value;
            Options.xyStableDots             = app.xyStableDots.Value;
            Options.Thresholds.ITMax         = 0;  % custom thresholds disabled
            Options.Thresholds.Vol           = 0;  % custom thresholds disabled
            Options.Thresholds.MeanBright    = 0;  % custom thresholds disabled
            Options.Thresholds.ITMaxDir      = 1;  % 1 means ">= than threshold"
            Options.Thresholds.VolDir        = 1;  % 1 means ">= than threshold"
            Options.Thresholds.MeanBrightDir = 1;  % 1 means ">= than threshold"
            
            app.Log.Value{end+1} = 'Filtering objects... ';
            app.Filter = filterObjects(app.Settings, app.Dots, Options);
            Filter = app.Filter;
            save([app.Settings.TPN 'Filter.mat'],'Filter');
            app.Log.Value{end} = [app.Log.Value{end} 'DONE'];
            
            load([app.Settings.TPN 'Filter.mat']);
            app.Filter = Filter;
            
            app.ValidateButton.Enable = 'on';
            app.Lamp.Color = 'green';
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            load([pwd filesep 'Settings.mat']);
            app.Settings = Settings;
            load([app.Settings.TPN 'Filter.mat']);
            app.Filter = Filter;
            
            app.Settings.Inspect3D.showPassing = app.Passing.Value;
            app.Settings.Inspect3D.showNonPassing = app.NonPassing.Value;
            switch app.InspectorType.Value
                case 'Volume Inspector'
                    inspectVolume2D(app.Post.*app.Mask, app.Dots, app.Filter);
                case 'Imaris 7'                    
                    exportObjectsToImaris(app.Settings, app.Dots, app.Filter); % Transfer objects to Imaris
            end
            % Reload the filter from file as user might have changed it
            load([pwd filesep 'Filter.mat']);
            app.Filter = Filter;

            % Calculate density of validated objects            
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            app.Log.Value{end+1} = 'Calculating density of validated objects... ';
            % If the cell has a skeleton proceed with these additional steps
            if exist([app.Settings.TPN 'Skel.mat'], 'file')
                load([app.Settings.TPN 'Skel.mat']);
                app.Skel = Skel;
                load([app.Settings.TPN 'Settings.mat']);
                app.Settings = Settings;
                app.Skel = calcSkelPathLength(app.Skel, false);
                Skel = app.Skel;
                save([app.Settings.TPN 'Skel.mat'],'Skel');
                app.Skel = generateFinerSkel(app.Skel, app.Settings.ImInfo.xyum, app.Settings.debug);
                app.Skel = calcSkelPathLength(app.Skel, false);
                Skel = app.Skel;
                save([app.Settings.TPN 'SkelFiner.mat'], 'Skel');
                app.Dots = distDotsToCellBody(app.Dots, app.Skel, app.Settings);
                app.Dots = distDotsToSkel(app.Dots, app.Skel, app.Settings);
                Dots = app.Dots;
                save([app.Settings.TPN 'Dots.mat'],'Dots');
                calcPathLengthStats(app.Settings, getFilteredObjects(app.Dots, app.Filter), app.Skel, false); % Plot distribution along dendrites
            
                % Generate heatmaps of object density
                app.Density = calcDensity(app.Settings, getFilteredObjects(app.Dots, app.Filter), app.Skel, false); 
                Density = app.Density;
                save([app.Settings.TPN 'Density.mat'],'Density');
                app.DensitybydepthButton.Enable = 'on';
                app.LocationsButton.Enable = 'on';
                app.DensitylinearButton.Enable = 'on';
                app.HeatmapButton.Enable = 'on';
                app.SkeletonButton.Enable = 'on';
                app.ShollAnalysisButton.Enable = 'on';                
            else
                % Compute and plot object distribution as function of volume depth
                app.Density = calcDotDensityAlongZ(app.Settings, getFilteredObjects(app.Dots, app.Filter), false);
                Density = app.Density;
                save([app.Settings.TPN 'Density'], 'Density');
                app.DensitybydepthButton.Enable = 'on';
                app.DensitylinearButton.Enable = 'off';
                app.LocationsButton.Enable = 'off';
                app.HeatmapButton.Enable = 'off';
                app.SkeletonButton.Enable = 'off';
                app.ShollAnalysisButton.Enable = 'off';                
            end            
            app.Log.Value{end} = [app.Log.Value{end} 'DONE'];
            app.Log.Value{end+1} = 'ObjectFinder analysis completed, display results using the plot tab.';
            
            app.Lamp.Color = 'green';
        end

        % Callback function
        function CalcDensityButtonPushed(app, event)
            app.Lamp.Color = 'yellow';
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            app.Log.Value{end+1} = 'Calculating density of validated objects... ';
            % If the cell has a skeleton proceed with these additional steps
            if exist([app.Settings.TPN 'Skel.mat'], 'file')
                load([app.Settings.TPN 'Skel.mat']);
                app.Skel = Skel;
                load([app.Settings.TPN 'Settings.mat']);
                app.Settings = Settings;
                app.Skel = calcSkelPathLength(app.Skel, false);
                Skel = app.Skel;
                save([app.Settings.TPN 'Skel.mat'],'Skel');
                app.Skel = generateFinerSkel(app.Skel, app.Settings.ImInfo.xyum, app.Settings.debug);
                app.Skel = calcSkelPathLength(app.Skel, false);
                Skel = app.Skel;
                save([app.Settings.TPN 'SkelFiner.mat'], 'Skel');
                app.Dots = distDotsToCellBody(app.Dots, app.Skel, app.Settings);
                app.Dots = distDotsToSkel(app.Dots, app.Skel, app.Settings);
                Dots = app.Dots;
                save([app.Settings.TPN 'Dots.mat'],'Dots');
                calcPathLengthStats(app.Settings, getFilteredObjects(app.Dots, app.Filter), app.Skel, false); % Plot distribution along dendrites
            
                % Generate heatmaps of object density
                app.Density = calcDensity(app.Settings, getFilteredObjects(app.Dots, app.Filter), app.Skel, false); 
                Density = app.Density;
                save([app.Settings.TPN 'Density.mat'],'Density');
                app.DensitybydepthButton.Enable = 'on';
                app.LocationsButton.Enable = 'on';
                app.DensitylinearButton.Enable = 'on';
                app.HeatmapButton.Enable = 'on';
                app.SkeletonButton.Enable = 'on';
                app.ShollAnalysisButton.Enable = 'on';                
            else
                % Compute and plot object distribution as function of volume depth
                app.Density = calcDotDensityAlongZ(app.Settings, getFilteredObjects(app.Dots, app.Filter), false);
                Density = app.Density;
                save([app.Settings.TPN 'Density'], 'Density');
                app.DensitybydepthButton.Enable = 'on';
                app.DensitylinearButton.Enable = 'off';
                app.LocationsButton.Enable = 'off';
                app.HeatmapButton.Enable = 'off';
                app.SkeletonButton.Enable = 'off';
                app.ShollAnalysisButton.Enable = 'off';                
            end            
            app.Log.Value{end} = [app.Log.Value{end} 'DONE'];
            app.Log.Value{end+1} = 'ObjectFinder analysis completed, display results using the plot tab.';
            app.Lamp.Color = 'green';
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if tmpDir
                cd(tmpDir);
                app.startupFcn;
            end
            
            app.Lamp.Color = 'green';
        end

        % Button pushed function: LocationsButton
        function LocationsButtonPushed(app, event)
            app.SkeletonButtonPushed;
            
            % Plot passing objects in UIAxes GUI component
            load([app.Settings.TPN 'Filter.mat']);
            app.Filter = Filter;            
            tmpPassing = getFilteredObjects(app.Dots, app.Filter);
            tmpA = zeros(numel(tmpPassing.Vol), 2);
            for i=1:numel(tmpPassing.Vol)
                tmpA(i,:)= mean(tmpPassing.Vox(i).Pos(:,1:2));
            end
            if app.PlotNewWinCheckBox.Value
                scatter(tmpA(:,1), tmpA(:,2), 25, 'o', 'MarkerEdgeColor', [0 0.5 0.5]);
                xlim([0 app.Settings.ImInfo.xNumVox]);
                ylim([0 app.Settings.ImInfo.yNumVox]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);                
            else
                scatter(app.UIAxes, tmpA(:,1), tmpA(:,2), 25, 'o', 'MarkerEdgeColor', [0 0.5 0.5]);
                xlim(app.UIAxes, [0 app.Settings.ImInfo.xNumVox]);
                ylim(app.UIAxes, [0 app.Settings.ImInfo.yNumVox]);
                box(app.UIAxes, 'on');
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                xlabel(app.UIAxes, '');
                ylabel(app.UIAxes, '');
                title(app.UIAxes, '');              
            end
        end

        % Button pushed function: SkeletonButton
        function SkeletonButtonPushed(app, event)
            load([app.Settings.TPN 'Skel.mat']);
            Skel.XYZ = Skel.FilStats.aXYZ;
            Skel.SomaPtID = Skel.FilStats.SomaPtID+1;
            Skel.FilStats.aEdges = Skel.FilStats.aEdges - Skel.FilStats.aEdges(1,1) + 1; % shift index edges so that first element starts with 1
            
            %% March edges to find matching pairs and recreate the branching pattern
            % For each branch first the march from cell body is computer, then
            % all points in common with previously calculated branches are removed
            % this leaves 1 segment per branch without any diplicate
            vNumberOfSpots = length(Skel.FilStats.aRad); % Store total number of points we need to iterate
            % Find position of terminal and biforcation points in the filament connectivity (aEdges)
            vNumberOfTerminals = 0;
            vTerminals = [];
            vNumberOfForks = 0;
            vForks = [];
            % Start investigating all spots except root (start from position #2 to exclude root)
            for vSpots = 2:vNumberOfSpots
                vEdge = find(Skel.FilStats.aEdges==vSpots);
                % if current edge is a terminal point of the skeleton, it should be listed once
                if length(vEdge)==1
                    % disp('found a terminal point');
                    vNumberOfTerminals = vNumberOfTerminals + 1;
                    vTerminals(vNumberOfTerminals) = vSpots;
                elseif length(vEdge)>2
                    % disp('found fork point');
                    vNumberOfForks = vNumberOfForks + 1;
                    vForks(vNumberOfForks) = vSpots;
                end
            end
                        
            % March backwards from each terminal point to the root in orher to find entire branch path
            vPaths=[]; % keeps an ongoing list of points already assigned to a branch
            for vTerminalIndex = 1:vNumberOfTerminals
                vLength = 1;
                vTerminal = vTerminals(vTerminalIndex); % start from terminal point
                vPath = vTerminal;  % add the terminal point to current path
                
                vFound = true;
                while vFound
                    % find among edges which one is connect to current terminal
                    % vEdge contains the number to wich vTerminal is connected
                    % vSide contains which side of the edge is vTerminal in this connection
                    [vEdge, vSide] = find(Skel.FilStats.aEdges==vTerminal);
                    
                    vFound = false;
                    for vNeighborIndex = 1:length(vEdge)
                        % looks like is marching both directions here
                        vNeighbor = Skel.FilStats.aEdges(vEdge(vNeighborIndex), 3-vSide(vNeighborIndex));
                        vNeighborSide = 3-vSide(vNeighborIndex); % Store side of the neighbor in the connectivity
                        if vNeighbor<vTerminal
                            vNewTerminal = vNeighbor;
                            vFound = true;
                        end
                    end
                    if vFound
                        vLength = vLength + 1;
                        vTerminal = vNewTerminal;
                        vPath(vLength) = vTerminal;
                    end
                end
                
                vPath = fliplr(vPath); % Flip path so that is not going terminal->root but root->terminal instead
                vPath = setdiff(vPath, vPaths); % Remove common part with the paths previously calculated
                vPaths = cat(2,vPaths, vPath); % Add current path to paths
                
                Skel.branches(vTerminalIndex).XYZ = Skel.FilStats.aXYZ(vPath,:);
                Skel.branches(vTerminalIndex).Rad = Skel.FilStats.aRad(vPath);
                %Skels(i).branches(vTerminalIndex).Edges = [1:vLength-1;2:vLength]';
                Skel.TotalBranches = numel(Skel.branches);
            end
            
            if app.PlotNewWinCheckBox.Value
                figure; hold on;
                for i=1:numel(Skel.branches)
                    plot(Skel.branches(i).XYZ(:,2)/app.Settings.ImInfo.xyum, Skel.branches(i).XYZ(:,1)/app.Settings.ImInfo.xyum, 'k-', 'LineWidth', 1.5);
                end
                scatter(Skel.FilStats.aXYZ(1,1)/app.Settings.ImInfo.xyum, Skel.FilStats.aXYZ(1,2)/app.Settings.ImInfo.xyum, 1000, 'o', 'LineWidth', 1.5, 'MarkerEdgeColor', [0.5 0 0.5]);
                xlim([1 app.Settings.ImInfo.xNumVox]);
                ylim([1 app.Settings.ImInfo.yNumVox]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
                pbaspect([1 1 1]);
            else
                cla(app.UIAxes);
                colorbar(app.UIAxes,'off');                
                hold(app.UIAxes, 'on');
                for i=1:numel(Skel.branches)
                    plot(app.UIAxes, Skel.branches(i).XYZ(:,2)/app.Settings.ImInfo.xyum, Skel.branches(i).XYZ(:,1)/app.Settings.ImInfo.xyum, 'k-', 'LineWidth', 1.5);
                end
                scatter(app.UIAxes, Skel.FilStats.aXYZ(1,1)/app.Settings.ImInfo.xyum, Skel.FilStats.aXYZ(1,2)/app.Settings.ImInfo.xyum, 1000, 'o', 'LineWidth', 1.5, 'MarkerEdgeColor', [0.5 0 0.5]);
                xlim(app.UIAxes, [1 app.Settings.ImInfo.xNumVox]);
                ylim(app.UIAxes, [1 app.Settings.ImInfo.yNumVox]);
                box(app.UIAxes, 'on');
                app.UIAxes.XTick= [];
                app.UIAxes.YTick= [];
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                xlabel(app.UIAxes, '');
                ylabel(app.UIAxes, '');
                title(app.UIAxes, '');                            
            end
        end

        % Button pushed function: DensitybydepthButton
        function DensitybydepthButtonPushed(app, event)
            % Compute and plot object distribution as function of volume depth
            app.Density = calcDotDensityAlongZ(app.Settings, getFilteredObjects(app.Dots, app.Filter), false);
            Density = app.Density;
            save([app.Settings.TPN 'Density'], 'Density');
            % Plot object density along Z on UIAxes GUI component
            sizeBin = app.Settings.ImInfo.xyum*app.Settings.ImInfo.xNumVox...
                *app.Settings.ImInfo.xyum*app.Settings.ImInfo.yNumVox...
                *app.Settings.ImInfo.zum*app.Density.binSize;
            tmpY = app.Density.densityPerc / sizeBin;
            tmpX = 1:100;
            if app.PlotNewWinCheckBox.Value
                plot(tmpX, tmpY, 'k', 'MarkerSize', 8);
                box off;
                xlim([0 100]);
                ylim([0 max(tmpY)]);
                set(gca, 'XTick', [0:10:100]);
                set(gca, 'YTickMode', 'auto');
                ylabel('Density (objects/µm^3)');
                xlabel('Volume depth percentage');                
            else
                cla(app.UIAxes);
                colorbar(app.UIAxes,'off');
                axis(app.UIAxes, 'on');
                plot(app.UIAxes, tmpX, tmpY, 'k', 'MarkerSize', 8);
                box(app.UIAxes, 'off');
                app.UIAxes.TickDir = 'out';
                xlim(app.UIAxes, [0 100]);
                ylim(app.UIAxes, [0 max(tmpY)]);
                app.UIAxes.XTickLabelMode = 'auto';
                app.UIAxes.XTickMode = 'auto';
                app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.YTickMode = 'auto';
                box(app.UIAxes, 'off');
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                title(app.UIAxes, '');
                ylabel(app.UIAxes, 'Density (objects/µm^3)');
                xlabel(app.UIAxes, 'Volume depth percentage');
            end
            if app.ExportDataCheckBox.Value
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                xlswrite([pwd filesep 'results' filesep 'Density_by_depth.xls'],[tmpX;tmpY]');
            end
            
        end

        % Button pushed function: DensitylinearButton
        function DensitylinearButtonPushed(app, event)
            load([app.Settings.TPN 'SkelFiner.mat']);
            app.Skel = Skel;
            Passing = getFilteredObjects(app.Dots, app.Filter);
            DotPathLengthList = app.Skel.FilStats.SkelPathLength2Soma(Passing.ClosestSkelIDs);
            EdgePathLengthList = app.Skel.FilStats.EdgePathLength2Soma;
            EdgeLengthList = app.Skel.SegStats.Lengths;
            EdgePathLengthMax = ceil(max(EdgePathLengthList));
            
            DistBin = 10; % Bin distance is 10 micron by default
            DistFromSoma = 5:1:EdgePathLengthMax;
            clear NumDots EdgeLengths;
            for i=1:length(DistFromSoma)
                NumDots(i) = length(find((DotPathLengthList>DistFromSoma(i)-DistBin/2) & (DotPathLengthList<=DistFromSoma(i)+DistBin/2)));
                EdgeLengths(i) = sum(EdgeLengthList((EdgePathLengthList>DistFromSoma(i)-DistBin/2) & (EdgePathLengthList<=DistFromSoma(i)+DistBin/2)));
            end
            PoverD = NumDots./EdgeLengths;
            
            if app.PlotNewWinCheckBox.Value
                plot(DistFromSoma, PoverD, 'b', 'LineWidth', 2);
                xlim([0 EdgePathLengthMax]);
                ylim([0 max(1, max(PoverD))]);
                xlabel('Distance from cell body (µm), binning = 10 µm');
                ylabel('Linear density (puncta / µm dendrite)');            
            else
                cla(app.UIAxes);
                colorbar(app.UIAxes,'off');
                axis(app.UIAxes, 'on');
                plot(app.UIAxes, DistFromSoma, PoverD, 'b', 'LineWidth', 2);
                xlim(app.UIAxes, [0 EdgePathLengthMax]);
                ylim(app.UIAxes, [0 max(1, max(PoverD))]);
                app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.YTickMode = 'auto';
                box(app.UIAxes, 'off');
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                title(app.UIAxes, '');              
                xlabel(app.UIAxes, 'Distance from cell body (µm), binning = 10 µm');
                ylabel(app.UIAxes, 'Linear density (puncta / µm dendrite)');                
            end    
            if app.ExportDataCheckBox.Value
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                xlswrite([pwd filesep 'results' filesep 'Density_Linear.xls'],[DistFromSoma;PoverD]');
            end
        end

        % Button pushed function: ShollAnalysisButton
        function ShollAnalysisButtonPushed(app, event)
            load([app.Settings.TPN 'Skel.mat']);
            app.Skel = Skel;
            app.Skel.XYZ = app.Skel.FilStats.aXYZ;
            app.Skel.SomaPtID = app.Skel.FilStats.SomaPtID+1;
            app.Skel.FilStats.aEdges = app.Skel.FilStats.aEdges - app.Skel.FilStats.aEdges(1,1) + 1; % shift index edges so that first element starts with 1
            
            %% March edges to find matching pairs and recreate the branching pattern
            % For each branch first the march from cell body is computer, then
            % all points in common with previously calculated branches are removed
            % this leaves 1 segment per branch without any diplicate
            vNumberOfSpots = length(app.Skel.FilStats.aRad); % Store total number of points we need to iterate
            % Find position of terminal and biforcation points in the filament connectivity (aEdges)
            vNumberOfTerminals = 0;
            vTerminals = [];
            vNumberOfForks = 0;
            vForks = [];
            % Start investigating all spots except root (start from position #2 to exclude root)
            for vSpots = 2:vNumberOfSpots
                vEdge = find(app.Skel.FilStats.aEdges==vSpots);
                % if current edge is a terminal point of the skeleton, it should be listed once
                if length(vEdge)==1
                    % disp('found a terminal point');
                    vNumberOfTerminals = vNumberOfTerminals + 1;
                    vTerminals(vNumberOfTerminals) = vSpots;
                elseif length(vEdge)>2
                    % disp('found fork point');
                    vNumberOfForks = vNumberOfForks + 1;
                    vForks(vNumberOfForks) = vSpots;
                end
            end
                        
            % March backwards from each terminal point to the root in orher to find entire branch path
            vPaths=[]; % keeps an ongoing list of points already assigned to a branch
            for vTerminalIndex = 1:vNumberOfTerminals
                vLength = 1;
                vTerminal = vTerminals(vTerminalIndex); % start from terminal point
                vPath = vTerminal;  % add the terminal point to current path
                
                vFound = true;
                while vFound
                    % find among edges which one is connect to current terminal
                    % vEdge contains the number to wich vTerminal is connected
                    % vSide contains which side of the edge is vTerminal in this connection
                    [vEdge, vSide] = find(app.Skel.FilStats.aEdges==vTerminal);
                    
                    vFound = false;
                    for vNeighborIndex = 1:length(vEdge)
                        % looks like is marching both directions here
                        vNeighbor = app.Skel.FilStats.aEdges(vEdge(vNeighborIndex), 3-vSide(vNeighborIndex));
                        vNeighborSide = 3-vSide(vNeighborIndex); % Store side of the neighbor in the connectivity
                        if vNeighbor<vTerminal
                            vNewTerminal = vNeighbor;
                            vFound = true;
                        end
                    end
                    if vFound
                        vLength = vLength + 1;
                        vTerminal = vNewTerminal;
                        vPath(vLength) = vTerminal;
                    end
                end
                
                vPath = fliplr(vPath); % Flip path so that is not going terminal->root but root->terminal instead
                vPath = setdiff(vPath, vPaths); % Remove common part with the paths previously calculated
                vPaths = cat(2,vPaths, vPath); % Add current path to paths
                
                app.Skel.branches(vTerminalIndex).XYZ = app.Skel.FilStats.aXYZ(vPath,:);
                app.Skel.branches(vTerminalIndex).Rad = app.Skel.FilStats.aRad(vPath);
                %Skels(i).branches(vTerminalIndex).Edges = [1:vLength-1;2:vLength]';
                app.Skel.TotalBranches = numel(app.Skel.branches);
            end                        
            app.Skel.Sholl = shollAnalysis(app.Skel, 1, false);
            
            if app.PlotNewWinCheckBox.Value
                plot(app.Skel.Sholl.intersections(:,1), app.Skel.Sholl.intersections(:,2), 'b', 'LineWidth', 2);
                xlim([0 app.Skel.Sholl.intersections(end,1)]);
                ylim([0 max(app.Skel.Sholl.intersections(:,2))]);
                xlabel('Distance from cell body (µm)');
                ylabel('Sholl intersections');
            else
                cla(app.UIAxes);
                colorbar(app.UIAxes,'off'); 
                axis(app.UIAxes, 'on');
                plot(app.UIAxes, app.Skel.Sholl.intersections(:,1), app.Skel.Sholl.intersections(:,2), 'b', 'LineWidth', 2);
                xlim(app.UIAxes, [0 app.Skel.Sholl.intersections(end,1)]);
                ylim(app.UIAxes, [0 max(app.Skel.Sholl.intersections(:,2))]);
                box(app.UIAxes, 'off');
                app.UIAxes.TickDir = 'out';
                app.UIAxes.XTickLabelMode = 'auto';
                app.UIAxes.XTickMode = 'auto';
                app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.YTickMode = 'auto';
                box(app.UIAxes, 'off');
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                title(app.UIAxes, '');
                xlabel(app.UIAxes, 'Distance from cell body (µm)');
                ylabel(app.UIAxes, 'Sholl intersections');                               
            end    
            if app.ExportDataCheckBox.Value
                if ~isfolder([app.Settings.TPN 'results']), mkdir([app.Settings.TPN 'results']); end
                xlswrite([app.Settings.TPN 'results' filesep 'Skeleton_Sholl.xls'],app.Skel.Sholl.intersections);
            end
        end

        % Button pushed function: HeatmapButton
        function HeatmapButtonPushed(app, event)
            % Drap heatmap of linear density (P/D)
            app.Density = calcDensity(app.Settings, getFilteredObjects(app.Dots, app.Filter), app.Skel, false);
            if app.PlotNewWinCheckBox.Value  
                image(app.Density.DotDist ./ app.Density.DendDist*256);
                cmap=jet(256);
                cmap(1,:)=0;
                colormap(cmap);
                title('Linear density (objects/µm, blue=0 red=1)');
                colorbar('Ytick',[]);
                axis image; % adding color bar changes image x y dimention, so resize the image.
                axis off    % remove the axis
            else
                cla(app.UIAxes);
                axis(app.UIAxes, 'on');                
                image(app.UIAxes, app.Density.DotDist ./ app.Density.DendDist*256);
                cmap=jet(256);
                cmap(1,:)=0;
                colormap(app.UIAxes, cmap);
                xlim(app.UIAxes, [1 size(app.Density.DotDist,1)]);
                ylim(app.UIAxes, [1 size(app.Density.DotDist,2)]);
                title(app.UIAxes, 'Linear density (objects/µm, blue=0 red=1)');
                colorbar(app.UIAxes,'off'); 
                axis(app.UIAxes, 'off'); % remove the axis                
            end
            
        end

        % Value changed function: Scheduler
        function SchedulerValueChanged(app, event)
            if app.Scheduler.Value
                app.ScheduledTime.Enable = 'on';
            else
                app.ScheduledTime.Enable = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.Timer)
                stop(app.Timer);
            end
            delete(app);
        end

        % Button pushed function: btnAdd
        function btnAddButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if tmpDir
                cd(tmpDir);
                LoadImagesButtonPushed(app, event);
                if isempty(app.lstBatch.Items)
                    app.lstBatch.Items = {tmpDir};
                else
                    app.lstBatch.Items{end+1} = tmpDir;
                end
            end
            
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnRemove
        function btnRemoveButtonPushed(app, event)
            idx = find(contains(app.lstBatch.Items, app.lstBatch.Value));
            app.lstBatch.Items(idx) = [];
        end

        % Button pushed function: btnClear
        function btnClearButtonPushed(app, event)
            app.lstBatch.Items = {};
        end

        % Button pushed function: btnDown
        function btnDownButtonPushed(app, event)
            idx = find(contains(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx < numel(app.lstBatch.Items)
                app.lstBatch.Items(idx) = app.lstBatch.Items(idx+1);
                app.lstBatch.Items{idx+1} = val;
                app.lstBatch.Value = val;                
            end
        end

        % Button pushed function: btnUp
        function btnUpButtonPushed(app, event)
            idx = find(contains(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx > 1
                app.lstBatch.Items(idx) = app.lstBatch.Items(idx-1);
                app.lstBatch.Items{idx-1} = val;
                app.lstBatch.Value = val;
            end
        end

        % Button pushed function: btnBottom
        function btnBottomButtonPushed(app, event)
            idx = find(contains(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx < numel(app.lstBatch.Items)
                app.lstBatch.Items(idx) = [];
                app.lstBatch.Items{end+1} = val;
                app.lstBatch.Value = val;                
            end            
        end

        % Button pushed function: btnTop
        function btnTopButtonPushed(app, event)
            idx = find(contains(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx > 1
                app.lstBatch.Items(idx) = [];
                app.lstBatch.Items = [{val}, app.lstBatch.Items];
                app.lstBatch.Value = val;
            end            
        end

        % Button pushed function: btnBatchStart
        function btnBatchStartButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Delay execution of batch processing if timer is enabled
            if app.BatchScheduler.Value                
                if app.BatchScheduledTime.Value > hour(datetime)
                    app.TimerDelay = round(app.BatchScheduledTime.Value*60 - hour(datetime)*60 - minute(datetime)); % Custom time is today
                else
                    app.TimerDelay = round(app.BatchScheduledTime.Value+24)*60 - hour(datetime)*60 - minute(datetime); %Custom time is tomorrow
                end
                app.BatchLog.Value{end+1} = ['Waiting ' num2str(app.TimerDelay) ' minutes, then start searching for objects... '];
                app.Timer = timer('TimerFcn',@(~,~)disp('Time is up.'),'StartDelay',app.TimerDelay*60);
                start(app.Timer);
                wait(app.Timer);
                delete(app.Timer);
                app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'Timer is up! starting analysis.'];
            end
            
            app.BatchLog.Value = {['-- Starting batch ' datestr(now)]};
            app.btnBatchStart.Enable = 'off';
            for i = 1:numel(app.lstBatch.Items)
                % Stop batch processing if Stop button is pressed
                if app.btnBatchStop.Value
                    break;
                else
                    app.BatchLog.Value{end+1} = ['Processing item # ' num2str(i)];
                end
                
                % Load files of the current item to process
                app.lstBatch.Value = app.lstBatch.Items{i};
                cd(app.lstBatch.Items{i});
                if ~isdir([pwd filesep 'I'])
                    app.BatchLog.Value{end+1} = ['Error: Working folder not valid for analysis ' pwd];
                    continue;
                end
                
                app.BatchLog.Value{end+1} = ['Working folder:' pwd];
                app.FolderField.Value = pwd;
                app.Settings.TPN = [pwd filesep];
                if exist([pwd filesep 'Settings.mat'], 'file')
                    load([pwd filesep 'Settings.mat']);
                    app.Settings = Settings;
                    app.Settings.TPN = [pwd filesep];
                end
                if exist([pwd filesep 'Post.mat'], 'file')
                    load([pwd filesep 'Post.mat']);
                    app.Post = Post;
                end
                if exist([pwd filesep 'Mask.mat'], 'file')
                    load([pwd filesep 'Mask.mat']);
                    app.Mask = Mask;
                end
                
                
                % Calculate volume of the minimum / maximum object sizes allowed, assuming they are spheres
                MaxDotSize = round((4/3*pi*(app.BatchMaxSizeXY.Value/2)*(app.BatchMaxSizeXY.Value/2)*(app.BatchMaxSizeZ.Value/2)) / (app.Settings.ImInfo.xyum*app.Settings.ImInfo.xyum*app.Settings.ImInfo.zum));
                MinDotSize = round((4/3*pi*(app.BatchMinSizeXY.Value/2)*(app.BatchMinSizeXY.Value/2)*(app.BatchMinSizeZ.Value/2)) / (app.Settings.ImInfo.xyum*app.Settings.ImInfo.xyum*app.Settings.ImInfo.zum));
                
                app.Settings.objfinder.blockBuffer= round(app.BatchMaxSizeXY.Value/app.Settings.ImInfo.xyum);  % Overlapping buffer region between search block should be as big as the biggest dot we want to measure to make sure we are not missing any.
                app.Settings.objfinder.blockSize = max(64, 3*app.Settings.objfinder.blockBuffer); % Use 64 (best for PC with 16Gb ram) or 3x blockBuffer (maximum speed), whichever bigger
                app.Settings.objfinder.thresholdStep = 2;             % step-size in the iterative search when looping through possible intensity values
                app.Settings.objfinder.maxDotSize = MaxDotSize;       % max dot size exclusion criteria for single-peak dot DURING ITERATIVE THRESHOLDING, NOT FINAL.
                app.Settings.objfinder.minDotSize= MinDotSize;        % min dot size exclusion criteria DURING ITERATIVE THRESHOLDING, NOT FINAL.
                app.Settings.objfinder.itMin = 2;                     % Minimum iterative threshold allowed to be analyzed as voxels belonging to any dot...filter to remove value '1' pass thresholds. value '2' is also mostly noise for PSD95 dots, so 3 is the good starting point HO 2/9/2011
                app.Settings.objfinder.minFinalDotITMax = 3;          % minimum ITMax allowed as FINAL dots. Any found dot whose ITMax is below this threshold value is removed from the registration into Dots. 5 will be the best for PSD95. HO 1/5/2010
                app.Settings.objfinder.watershed = app.BatchWatershed.Value; % False if you want to skip watershed segmentation during object search
                Settings = app.Settings;
                save([app.Settings.TPN 'Settings.mat'], 'Settings');
                
                app.BatchLog.Value{end+1} = 'Searching objects in the volume (please wait)... ';
                % Seach objects inside the masked volume
                app.Dots = findObjects(app.Post.*app.Mask, app.Settings);
                app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'DONE'];
                app.BatchLog.Value{end+1} = 'Calculating sphericity of objects... ';
                % Create fields about sphericity of objects (Rounding)
                if app.BatchSphericity.Value, app.Dots = fitSphere(app.Dots, app.Settings); end
                app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'DONE'];
                
                Dots= app.Dots;
                save([app.Settings.TPN 'Dots.mat'],'Dots');
                Settings = app.Settings;
                save([app.Settings.TPN 'Settings.mat'],'Settings');
                
                % Filter objects according to filter panel settings
                tmpOptions.EdgeDotCut = app.BatchEdgeDotCut.Value;
                tmpOptions.SingleZDotCut = app.BatchSingleZDotCut.Value;
                tmpOptions.xyStableDots = app.BatchxyStableDots.Value;
                tmpOptions.Thresholds.ITMax = 0;
                tmpOptions.Thresholds.Vol = 0;
                tmpOptions.Thresholds.MeanBright = 0;
                switch app.BatchThreshParam.Value
                    case 'ITMax'
                        Options.Thresholds.ITMax = app.BatchThreshValue.Value;
                        Options.Thresholds.ITMaxDir = 1;
                    case 'Volume'
                        Options.Thresholds.Vol = app.BatchThreshValue.Value;
                        Options.Thresholds.VolDir = 1;
                    case 'Brightness'
                        Options.Thresholds.MeanBright = app.BatchThreshValue.Value;
                        Options.Thresholds.MeanBrightDir = 1;
                end
                app.BatchLog.Value{end+1} = 'Filtering objects... ';
                app.Filter = filterObjects(app.Settings, app.Dots, Options);
                Filter = app.Filter;
                save([app.Settings.TPN 'Filter.mat'],'Filter');
                app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'DONE'];
                app.BatchLog.Value{end+1} = ' ';
                pause(0.5); % pause to allow time for the loop to detect Stop button state
            end
            if app.btnBatchStop.Value
                app.BatchLog.Value{end+1} = '-- Processing interrupted by user.';
                app.btnBatchStop.Value = false;
            else
                app.BatchLog.Value{end+1} = ['-- Batch complete! ' datestr(now) ' ---'];
            end
            app.btnBatchStart.Enable = 'on';
            app.Lamp.Color = 'green';            
        end

        % Value changed function: BatchScheduler
        function BatchSchedulerValueChanged(app, event)
            if app.BatchScheduler.Value
                app.BatchScheduledTime.Enable = 'on';
            else
                app.BatchScheduledTime.Enable = 'off';
            end            
        end

        % Callback function
        function btnColoAddButtonPushed(app, event)
            [FileName, PathName] = uigetfile([pwd filesep 'I' filesep '*.tif']);
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if FileName
                if isempty(app.lstColo.Items)
                    app.lstColo.Items = {FileName};
                else
                    app.lstColo.Items{end+1} = FileName;
                end
                app.lstColo.Value = FileName;
            end            
            
        end

        % Callback function
        function btnColoRemoveButtonPushed(app, event)
            idx = find(contains(app.lstColo.Items, app.lstColo.Value));
            app.lstColo.Items(idx) = [];            
        end

        % Button pushed function: btnColocalize
        function btnColocalizeButtonPushed(app, event)
            % Load channel to colocalize
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            PathName = [pwd filesep 'I' filesep];
            FileName = app.lstColo.Value{1};
            if ~isempty(FileName)
                ImInfo = imfinfo([PathName FileName]);
                Colo = zeros(ImInfo(1).Height, ImInfo(1).Width, length(ImInfo));
                for j = 1:length(ImInfo)
                    Colo(:,:,j)=imread([PathName FileName], j);
                end
                save([pwd filesep 'Colo.mat'],'Colo', '-v7.3');
                
                colocManual(app.Dots, app.Filter, app.Post, Colo, [PathName FileName], [], '');
                app.btnColoDoneRefreshButtonPushed;
            end
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnColoDoneRemove
        function btnColoDoneRemoveButtonPushed(app, event)
            idx = find(contains(app.lstColoDone.Items, app.lstColoDone.Value));
            if numel(app.Coloc) > 1
                app.Coloc(idx) = [];
                Coloc = app.Coloc;
                save([app.Settings.TPN 'Coloc.mat'], 'Coloc'); 
            else
                app.btnColoDoneClearButtonPushed;
            end
            app.btnColoDoneRefreshButtonPushed;
        end

        % Button pushed function: btnColoDoneClear
        function btnColoDoneClearButtonPushed(app, event)
            app.Coloc = struct;
            delete([app.Settings.TPN 'Coloc.mat']);
            app.btnColoDoneRefreshButtonPushed;
        end

        % Callback function
        function btnColoManualRepeatButtonPushed(app, event)
            
        end

        % Button pushed function: btnColoRedo
        function btnColoRedoButtonPushed(app, event)
            % Load channel to colocalize
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            PathName = [pwd filesep 'I' filesep];
            FileName = app.lstColoDone.Value{1};
            if ~isempty(FileName)
                ImInfo = imfinfo([PathName FileName]);
                Colo = zeros(ImInfo(1).Height, ImInfo(1).Width, length(ImInfo));
                for j = 1:length(ImInfo)
                    Colo(:,:,j)=imread([PathName FileName], j);
                end
                save([app.Settings.TPN 'Colo.mat'],'Colo', '-v7.3');
                
                colocManual(app.Dots, app.Filter, app.Post, Colo, [PathName FileName]);
                app.btnColoDoneRefreshButtonPushed;
            end
            app.Lamp.Color = 'green';            
        end

        % Button pushed function: btnColoDoneRefresh
        function btnColoDoneRefreshButtonPushed(app, event)
            % Refresh list of processed channels
            app.lstColoDone.Items = {};
            if exist([pwd filesep 'Coloc.mat'], 'file')
                load([pwd filesep 'Coloc.mat']);
                app.Coloc = Coloc;
                for i=1:numel(app.Coloc)
                    if isempty(app.lstColo.Items)
                        app.lstColoDone.Items = {[app.Coloc(i).Fish1 '.tif']};
                    else
                        app.lstColoDone.Items{end+1} = [app.Coloc(i).Fish1 '.tif'];
                    end                    
                end
            end
            app.lstColoDoneValueChanged;
        end

        % Value changed function: lstColoDone
        function lstColoDoneValueChanged(app, event)
            %get index of selected values
            %idx = find(contains(app.lstColoDone.Items, app.lstColoDone.Value),1);
            [~, idx] = ismember(app.lstColoDone.Value, app.lstColoDone.Items);  
            
            switch numel(app.lstColoDone.Value) 
                case 1 % User selected only one item on screen, display colocalization stats
                    app.btnColoDouble.Enable     = 'off';
                    app.btnColoRedo.Enable       = 'on';
                    app.lblReferenceName.Text    = ['Reference: ' app.Coloc(idx).Source];
                    app.lblColoFishName.Text     = ['Channel: ' app.Coloc(idx).Fish1];
                    app.lblColo2FishName.Text    = 'Channel: none';
                    app.lblColocNumObjects.Text  = ['Processed: '...
                        num2str(app.Coloc(idx).TotalNumDotsManuallyColocAnalyzed) ' / '...
                        num2str(numel(app.Coloc(idx).ListDotIDsManuallyColocAnalyzed))];
                    app.lblColoc2NumObjects.Text = 'Processed: -/-';
                    app.lblColocRate.Text        = ['Colocalization rate: ' num2str(100*app.Coloc(idx).ColocRate) '%'];
                    app.lblColoc2Rate.Text       = 'Colocalization rate: N/A';
                    app.lblColocRateDouble.Text  = 'Double colocalized: N/A';
                    
                case 2 % User selected two items from list, display double colocalization stats
                    app.btnColoDouble.Enable     = 'on';
                    app.btnColoRedo.Enable       = 'off';
                    app.lblReferenceName.Text    = ['Reference: ' app.Coloc(idx(1)).Source];
                    app.lblColoFishName.Text     = ['Channel: ' app.Coloc(idx(1)).Fish1];
                    app.lblColo2FishName.Text    = ['Channel: ' app.Coloc(idx(2)).Fish1];
                    app.lblColocNumObjects.Text  = ['Processed: '...
                        num2str(app.Coloc(idx(1)).TotalNumDotsManuallyColocAnalyzed) ' / '...
                        num2str(numel(app.Coloc(idx(1)).ListDotIDsManuallyColocAnalyzed))];
                    app.lblColoc2NumObjects.Text = ['Processed: '...
                        num2str(app.Coloc(idx(2)).TotalNumDotsManuallyColocAnalyzed) ' / '...
                        num2str(numel(app.Coloc(idx(2)).ListDotIDsManuallyColocAnalyzed))];
                    app.lblColocRate.Text        = ['Colocalization rate: ' num2str(100*app.Coloc(idx(1)).ColocRate) '%'];
                    app.lblColoc2Rate.Text       = ['Colocalization rate: ' num2str(100*app.Coloc(idx(2)).ColocRate) '%'];
                    DoubleColocalized            = app.Coloc(idx(1)).ColocFlag==1 & app.Coloc(idx(2)).ColocFlag==1;
                    app.lblColocRateDouble.Text  = ['Double colocalized: ' num2str(100*numel(find(DoubleColocalized)) / numel(DoubleColocalized)) '%'];

                otherwise % Nothing selected or more than two items selected, do not display any info
                    app.btnColoDouble.Enable     = 'off';
                    app.btnColoRedo.Enable       = 'off';
                    app.lblReferenceName.Text    = 'Reference: none';
                    app.lblColoFishName.Text     = 'Channel: none';
                    app.lblColo2FishName.Text    = 'Channel: none';
                    app.lblColocNumObjects.Text  = 'Processed: -/-';
                    app.lblColoc2NumObjects.Text = 'Processed: -/-';
                    app.lblColocRate.Text        = 'Colocalization rate: N/A';
                    app.lblColoc2Rate.Text       = 'Colocalization rate: N/A';
                    app.lblColocRateDouble.Text  = 'Double colocalized: N/A'; 
            end
        end

        % Button pushed function: btnColoDouble
        function btnColoDoubleButtonPushed(app, event)
            % Load channel to colocalize
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            PathName = [pwd filesep 'I' filesep];
            FileName1 = app.lstColoDone.Value{1};
            if ~isempty(FileName1)
                ImInfo = imfinfo([PathName FileName1]);
                Colo = zeros(ImInfo(1).Height, ImInfo(1).Width, length(ImInfo));
                for j = 1:length(ImInfo)
                    Colo(:,:,j)=imread([PathName FileName1], j);
                end
                save([pwd filesep 'Colo.mat'],'Colo', '-v7.3');
            end

            FileName2 = app.lstColoDone.Value{2};
            if ~isempty(FileName2)
                ImInfo = imfinfo([PathName FileName2]);
                Colo2 = zeros(ImInfo(1).Height, ImInfo(1).Width, length(ImInfo));
                for j = 1:length(ImInfo)
                    Colo2(:,:,j)=imread([PathName FileName2], j);
                end
                save([pwd filesep 'Colo2.mat'],'Colo2', '-v7.3');
            end

            %get index of selected values
            [~, idx] = ismember(app.lstColoDone.Value, app.lstColoDone.Items);            
            ColocManual = app.Coloc(idx(1));
            ColocManual2 = app.Coloc(idx(2));

            colocManualCheckDouble(app.Dots, app.Filter, app.Post, Colo, ColocManual, Colo2, ColocManual2);
            app.btnColoDoneRefreshButtonPushed;
            
            app.Lamp.Color = 'green';
        end

        % Value changed function: lstColo
        function lstColoValueChanged(app, event)
            switch numel(app.lstColo.Value) 
                case 1
                    app.btnColocalize.Enable  = 'on';
                    app.btnColocalize2.Enable = 'off';

                case 2
                    app.btnColocalize.Enable  = 'off';
                    app.btnColocalize2.Enable = 'on';
                otherwise
                    app.btnColocalize.Enable  = 'off';
                    app.btnColocalize2.Enable = 'off';
            end        
        end

        % Button pushed function: btnColocalize2
        function btnColocalize2ButtonPushed(app, event)
            % Load channel to colocalize
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            PathName = [pwd filesep 'I' filesep];
            FileName1 = app.lstColo.Value{1};
            if ~isempty(FileName1)
                ImInfo = imfinfo([PathName FileName1]);
                Colo = zeros(ImInfo(1).Height, ImInfo(1).Width, length(ImInfo));
                for j = 1:length(ImInfo)
                    Colo(:,:,j)=imread([PathName FileName1], j);
                end
                save([pwd filesep 'Colo.mat'],'Colo', '-v7.3');
            end

            FileName2 = app.lstColo.Value{2};
            if ~isempty(FileName2)
                ImInfo = imfinfo([PathName FileName2]);
                Colo2 = zeros(ImInfo(1).Height, ImInfo(1).Width, length(ImInfo));
                for j = 1:length(ImInfo)
                    Colo2(:,:,j)=imread([PathName FileName2], j);
                end
                save([pwd filesep 'Colo2.mat'],'Colo2', '-v7.3');
            end
                
            colocManual(app.Dots, app.Filter, app.Post, Colo, [PathName FileName1], Colo2, [PathName FileName2]);
            app.btnColoDoneRefreshButtonPushed;
            
            app.Lamp.Color = 'green';
            
        end

        % Button pushed function: btnColoDoneUp
        function btnColoDoneUpButtonPushed(app, event)
            idx = find(contains(app.lstColoDone.Items, app.lstColoDone.Value),1);
            val = app.lstColoDone.Value;
            if idx > 1
                tmpColoc = app.Coloc(idx-1);
                app.Coloc(idx-1) = app.Coloc(idx);
                app.Coloc(idx) = tmpColoc;
                Coloc = app.Coloc;
                save([app.Settings.TPN 'Coloc.mat'], 'Coloc');                
                app.btnColoDoneRefreshButtonPushed;
                app.lstColoDone.Value = val;
            end
        end

        % Button pushed function: btnColoDoneDown
        function btnColoDoneDownButtonPushed(app, event)
            idx = find(contains(app.lstColoDone.Items, app.lstColoDone.Value),1);
            val = app.lstColoDone.Value;
            if idx < numel(app.lstColoDone.Items)
                tmpColoc = app.Coloc(idx+1);
                app.Coloc(idx+1) = app.Coloc(idx);
                app.Coloc(idx) = tmpColoc;
                Coloc = app.Coloc;
                save([app.Settings.TPN 'Coloc.mat'], 'Coloc');                
                app.btnColoDoneRefreshButtonPushed;                
                app.lstColoDone.Value = val;                
            end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ObjectFinder';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Lamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetectTab = uitab(app.TabGroup);
            app.DetectTab.Title = 'Detect';
            app.FolderButton = uibutton(app.DetectTab, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Icon = '48px-HomeFolder.png';
            app.FolderButton.Text = 'Folder';
            app.LoadImagesButton = uibutton(app.DetectTab, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Icon = '48px-Image_upload.png';
            app.LoadImagesButton.Enable = 'off';
            app.LoadImagesButton.Text = 'Load Images';
            app.FindObjectsButton = uibutton(app.DetectTab, 'push');
            app.FindObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @FindObjectsButtonPushed, true);
            app.FindObjectsButton.Icon = '48px-FindObjects.png';
            app.FindObjectsButton.Enable = 'off';
            app.FindObjectsButton.Text = 'Find Objects';
            app.FilterButton = uibutton(app.DetectTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Icon = '48px-Filter.png';
            app.FilterButton.Enable = 'off';
            app.FilterButton.Text = 'Filter';
            app.ValidateButton = uibutton(app.DetectTab, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Icon = '48px-Inspect.png';
            app.ValidateButton.Enable = 'off';
            app.ValidateButton.Text = 'Validate';
            app.SearchalgorithmsettingsPanel = uipanel(app.DetectTab);
            app.SearchalgorithmsettingsPanel.Title = 'Search algorithm settings';
            app.MaximumobjectdiametermXYLabel = uilabel(app.SearchalgorithmsettingsPanel);
            app.MaximumobjectdiametermXYLabel.Text = 'Maximum object diameter (µm) XY:';
            app.MaxSizeXY = uieditfield(app.SearchalgorithmsettingsPanel, 'numeric');
            app.MaxSizeXY.Limits = [0 Inf];
            app.MaxSizeXY.Value = 1;
            app.ZLabel = uilabel(app.SearchalgorithmsettingsPanel);
            app.ZLabel.Text = 'Z:';
            app.MaxSizeZ = uieditfield(app.SearchalgorithmsettingsPanel, 'numeric');
            app.MaxSizeZ.Limits = [0 Inf];
            app.MaxSizeZ.Value = 2;
            app.ZLabel_2 = uilabel(app.SearchalgorithmsettingsPanel);
            app.ZLabel_2.Text = 'Z:';
            app.MinSizeZ = uieditfield(app.SearchalgorithmsettingsPanel, 'numeric');
            app.MinSizeZ.Value = 0.5;
            app.Watershed = uicheckbox(app.SearchalgorithmsettingsPanel);
            app.Watershed.Text = 'Split multi-peak objects by watershed segmentation';
            app.Watershed.Value = true;
            app.MinimumobjectdiametermXYLabel = uilabel(app.SearchalgorithmsettingsPanel);
            app.MinimumobjectdiametermXYLabel.Text = 'Minimum object diameter (µm) XY:';
            app.MinSizeXY = uieditfield(app.SearchalgorithmsettingsPanel, 'numeric');
            app.MinSizeXY.Value = 0.25;
            app.Sphericity = uicheckbox(app.SearchalgorithmsettingsPanel);
            app.Sphericity.Text = 'Calculate sphericity';
            app.Scheduler = uicheckbox(app.SearchalgorithmsettingsPanel);
            app.Scheduler.ValueChangedFcn = createCallbackFcn(app, @SchedulerValueChanged, true);
            app.Scheduler.Text = 'Start at custom time of day (24h):';
            app.ScheduledTime = uiknob(app.SearchalgorithmsettingsPanel, 'continuous');
            app.ScheduledTime.Limits = [1 24];
            app.ScheduledTime.Enable = 'off';
            app.ScheduledTime.Value = 19;
            app.FiltersettingsexcludeobjectsifPanel = uipanel(app.DetectTab);
            app.FiltersettingsexcludeobjectsifPanel.Title = 'Filter settings (exclude objects if ...)';
            app.EdgeDotCut = uicheckbox(app.FiltersettingsexcludeobjectsifPanel);
            app.EdgeDotCut.Text = 'Touching the mask''s edge';
            app.SingleZDotCut = uicheckbox(app.FiltersettingsexcludeobjectsifPanel);
            app.SingleZDotCut.Text = 'On a sigle Z plane';
            app.SingleZDotCut.Value = true;
            app.xyStableDots = uicheckbox(app.FiltersettingsexcludeobjectsifPanel);
            app.xyStableDots.Text = 'Unstable centroids';
            app.ActivitylogTextAreaLabel = uilabel(app.DetectTab);
            app.ActivitylogTextAreaLabel.Text = 'Activity log';
            app.Log = uitextarea(app.DetectTab);
            app.Log.Editable = 'off';
            app.ValidationsettingsPanel = uipanel(app.DetectTab);
            app.ValidationsettingsPanel.Title = 'Validation settings';
            app.Passing = uicheckbox(app.ValidationsettingsPanel);
            app.Passing.Text = 'Visualize objects validated by filter';
            app.Passing.Value = true;
            app.NonPassing = uicheckbox(app.ValidationsettingsPanel);
            app.NonPassing.Text = 'Visualize objects rejected by filter';
            app.ValidationmethodLabel = uilabel(app.ValidationsettingsPanel);
            app.ValidationmethodLabel.Text = 'Validation method';
            app.InspectorType = uidropdown(app.ValidationsettingsPanel);
            app.InspectorType.Items = {'Volume Inspector', 'Imaris 7'};
            app.InspectorType.Value = 'Volume Inspector';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.DensitybydepthButton = uibutton(app.PlotTab, 'push');
            app.DensitybydepthButton.ButtonPushedFcn = createCallbackFcn(app, @DensitybydepthButtonPushed, true);
            app.DensitybydepthButton.Enable = 'off';
            app.DensitybydepthButton.Text = 'Density by depth';
            app.LocationsButton = uibutton(app.PlotTab, 'push');
            app.LocationsButton.ButtonPushedFcn = createCallbackFcn(app, @LocationsButtonPushed, true);
            app.LocationsButton.Enable = 'off';
            app.LocationsButton.Text = 'Locations';
            app.HeatmapButton = uibutton(app.PlotTab, 'push');
            app.HeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @HeatmapButtonPushed, true);
            app.HeatmapButton.Enable = 'off';
            app.HeatmapButton.Text = 'Heatmap';
            app.DensitylinearButton = uibutton(app.PlotTab, 'push');
            app.DensitylinearButton.ButtonPushedFcn = createCallbackFcn(app, @DensitylinearButtonPushed, true);
            app.DensitylinearButton.Enable = 'off';
            app.DensitylinearButton.Text = 'Density linear';
            app.SkeletonButton = uibutton(app.PlotTab, 'push');
            app.SkeletonButton.ButtonPushedFcn = createCallbackFcn(app, @SkeletonButtonPushed, true);
            app.SkeletonButton.Enable = 'off';
            app.SkeletonButton.Text = 'Skeleton';
            app.ShollAnalysisButton = uibutton(app.PlotTab, 'push');
            app.ShollAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ShollAnalysisButtonPushed, true);
            app.ShollAnalysisButton.Enable = 'off';
            app.ShollAnalysisButton.Text = 'Sholl Analysis';
            app.PlotNewWinCheckBox = uicheckbox(app.PlotTab);
            app.PlotNewWinCheckBox.Text = 'Plot in new window';
            app.ExportDataCheckBox = uicheckbox(app.PlotTab);
            app.ExportDataCheckBox.Text = 'Export data in Excel';
            app.UIAxes = uiaxes(app.PlotTab);
            app.AutomateTab = uitab(app.TabGroup);
            app.AutomateTab.Title = 'Automate';
            app.BatchSearchSettings = uipanel(app.AutomateTab);
            app.BatchSearchSettings.Title = 'Search algorithm settings';
            app.MaxSizeXY_2Label = uilabel(app.BatchSearchSettings);
            app.MaxSizeXY_2Label.Text = 'Maximum object size (µm) XY:';
            app.BatchMaxSizeXY = uieditfield(app.BatchSearchSettings, 'numeric');
            app.BatchMaxSizeXY.Limits = [0 Inf];
            app.BatchMaxSizeXY.Value = 1;
            app.MaxSizeZ_2Label = uilabel(app.BatchSearchSettings);
            app.MaxSizeZ_2Label.Text = 'Z:';
            app.BatchMaxSizeZ = uieditfield(app.BatchSearchSettings, 'numeric');
            app.BatchMaxSizeZ.Limits = [0 Inf];
            app.BatchMaxSizeZ.Value = 2;
            app.MinSizeZ_2Label = uilabel(app.BatchSearchSettings);
            app.MinSizeZ_2Label.Text = 'Z:';
            app.BatchMinSizeZ = uieditfield(app.BatchSearchSettings, 'numeric');
            app.BatchMinSizeZ.Value = 0.5;
            app.BatchWatershed = uicheckbox(app.BatchSearchSettings);
            app.BatchWatershed.Text = 'Watershed multi-peak objects';
            app.BatchWatershed.Value = true;
            app.MinSizeXY_2Label = uilabel(app.BatchSearchSettings);
            app.MinSizeXY_2Label.Text = 'Minimum object size (µm) XY:';
            app.BatchMinSizeXY = uieditfield(app.BatchSearchSettings, 'numeric');
            app.BatchMinSizeXY.Value = 0.25;
            app.BatchSphericity = uicheckbox(app.BatchSearchSettings);
            app.BatchSphericity.Text = 'Calculate sphericity';
            app.BatchFilterSettings = uipanel(app.AutomateTab);
            app.BatchFilterSettings.Title = 'Filter settings';
            app.BatchEdgeDotCut = uicheckbox(app.BatchFilterSettings);
            app.BatchEdgeDotCut.Text = 'Exclude objects touching the mask''s edge';
            app.BatchEdgeDotCut.Value = true;
            app.BatchSingleZDotCut = uicheckbox(app.BatchFilterSettings);
            app.BatchSingleZDotCut.Text = 'Exclude objects on a sigle Z plane';
            app.BatchSingleZDotCut.Value = true;
            app.BatchxyStableDots = uicheckbox(app.BatchFilterSettings);
            app.BatchxyStableDots.Text = 'Exclude objects with unstable centroids';
            app.ThresholdingparameterLabel = uilabel(app.BatchFilterSettings);
            app.ThresholdingparameterLabel.Text = 'Thresholding parameter';
            app.BatchThreshParam = uidropdown(app.BatchFilterSettings);
            app.BatchThreshParam.Items = {'None', 'ITMax', 'Volume', 'Brightness'};
            app.BatchThreshParam.Value = 'None';
            app.CutoffvalueLabel = uilabel(app.BatchFilterSettings);
            app.CutoffvalueLabel.Text = 'Cutoff value >=';
            app.BatchThreshValue = uieditfield(app.BatchFilterSettings, 'numeric');
            app.ProcessinglistPanel = uipanel(app.AutomateTab);
            app.ProcessinglistPanel.Title = 'Processing list';
            app.lstBatch = uilistbox(app.ProcessinglistPanel);
            app.lstBatch.Items = {};
            app.lstBatch.Value = {};
            app.btnAdd = uibutton(app.ProcessinglistPanel, 'push');
            app.btnAdd.ButtonPushedFcn = createCallbackFcn(app, @btnAddButtonPushed, true);
            app.btnAdd.Icon = '48px-List-add.png';
            app.btnAdd.Text = '';
            app.btnRemove = uibutton(app.ProcessinglistPanel, 'push');
            app.btnRemove.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveButtonPushed, true);
            app.btnRemove.Icon = '48px-List-remove.png';
            app.btnRemove.Text = '';
            app.btnUp = uibutton(app.ProcessinglistPanel, 'push');
            app.btnUp.ButtonPushedFcn = createCallbackFcn(app, @btnUpButtonPushed, true);
            app.btnUp.Icon = '48px-Go-up.png';
            app.btnUp.Text = '';
            app.btnDown = uibutton(app.ProcessinglistPanel, 'push');
            app.btnDown.ButtonPushedFcn = createCallbackFcn(app, @btnDownButtonPushed, true);
            app.btnDown.Icon = '48px-Go-down.png';
            app.btnDown.Text = '';
            app.btnTop = uibutton(app.ProcessinglistPanel, 'push');
            app.btnTop.ButtonPushedFcn = createCallbackFcn(app, @btnTopButtonPushed, true);
            app.btnTop.Icon = '48px-Go-top.png';
            app.btnTop.Text = '';
            app.btnBottom = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBottom.ButtonPushedFcn = createCallbackFcn(app, @btnBottomButtonPushed, true);
            app.btnBottom.Icon = '48px-Go-bottom.png';
            app.btnBottom.Text = '';
            app.btnClear = uibutton(app.ProcessinglistPanel, 'push');
            app.btnClear.ButtonPushedFcn = createCallbackFcn(app, @btnClearButtonPushed, true);
            app.btnClear.Icon = '48px-Edit-clear.png';
            app.btnClear.Text = '';
            app.btnBatchStart = uibutton(app.AutomateTab, 'push');
            app.btnBatchStart.ButtonPushedFcn = createCallbackFcn(app, @btnBatchStartButtonPushed, true);
            app.btnBatchStart.Icon = '72px-Batch-process.png';
            app.btnBatchStart.Text = 'Start';
            app.BatchLog = uitextarea(app.AutomateTab);
            app.BatchLog.Editable = 'off';
            app.BatchLog.Value = {'Activity log:'};
            app.BatchScheduledTime = uiknob(app.AutomateTab, 'continuous');
            app.BatchScheduledTime.Limits = [1 24];
            app.BatchScheduledTime.Enable = 'off';
            app.BatchScheduledTime.Value = 19;
            app.BatchScheduler = uicheckbox(app.AutomateTab);
            app.BatchScheduler.ValueChangedFcn = createCallbackFcn(app, @BatchSchedulerValueChanged, true);
            app.BatchScheduler.Text = 'Start at a custom time';
            app.btnBatchStop = uibutton(app.AutomateTab, 'state');
            app.btnBatchStop.Icon = '48px-stop-hand.png';
            app.btnBatchStop.Text = 'Stop';
            app.ColocalizeTab = uitab(app.TabGroup);
            app.ColocalizeTab.Title = 'Colocalize';
            app.ChannelsavailableforcolocalizionanalysisPanel = uipanel(app.ColocalizeTab);
            app.ChannelsavailableforcolocalizionanalysisPanel.Title = 'Channels available for colocalizion analysis';
            app.lstColo = uilistbox(app.ChannelsavailableforcolocalizionanalysisPanel);
            app.lstColo.Items = {};
            app.lstColo.Multiselect = 'on';
            app.lstColo.ValueChangedFcn = createCallbackFcn(app, @lstColoValueChanged, true);
            app.lstColo.Value = {};
            app.btnColocalize = uibutton(app.ChannelsavailableforcolocalizionanalysisPanel, 'push');
            app.btnColocalize.ButtonPushedFcn = createCallbackFcn(app, @btnColocalizeButtonPushed, true);
            app.btnColocalize.Icon = '48px-Fruit-cherries.png';
            app.btnColocalize.Enable = 'off';
            app.btnColocalize.Text = {'Colocalize'; '1 channel'};
            app.btnColocalize2 = uibutton(app.ChannelsavailableforcolocalizionanalysisPanel, 'push');
            app.btnColocalize2.ButtonPushedFcn = createCallbackFcn(app, @btnColocalize2ButtonPushed, true);
            app.btnColocalize2.Icon = '48px-Fruit-3cherries.png';
            app.btnColocalize2.Enable = 'off';
            app.btnColocalize2.Text = {'Colocalize'; '2 channels'};
            app.ProcessedchannelsPanel = uipanel(app.ColocalizeTab);
            app.ProcessedchannelsPanel.Title = 'Processed channels';
            app.lstColoDone = uilistbox(app.ProcessedchannelsPanel);
            app.lstColoDone.Items = {};
            app.lstColoDone.Multiselect = 'on';
            app.lstColoDone.ValueChangedFcn = createCallbackFcn(app, @lstColoDoneValueChanged, true);
            app.lstColoDone.Value = {};
            app.btnColoDoneRemove = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneRemove.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneRemoveButtonPushed, true);
            app.btnColoDoneRemove.Icon = '48px-List-remove.png';
            app.btnColoDoneRemove.Text = '';
            app.btnColoDoneClear = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneClear.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneClearButtonPushed, true);
            app.btnColoDoneClear.Icon = '48px-Edit-clear.png';
            app.btnColoDoneClear.Text = '';
            app.btnColoRedo = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoRedo.ButtonPushedFcn = createCallbackFcn(app, @btnColoRedoButtonPushed, true);
            app.btnColoRedo.Icon = '48px-Fruit-recherries.png';
            app.btnColoRedo.Enable = 'off';
            app.btnColoRedo.Text = 'Reprocess';
            app.lblColocNumObjects = uilabel(app.ProcessedchannelsPanel);
            app.lblColocNumObjects.Text = 'Processed:';
            app.lblColocRate = uilabel(app.ProcessedchannelsPanel);
            app.lblColocRate.Text = 'Colocalization rate:';
            app.btnColoDoneRefresh = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneRefresh.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneRefreshButtonPushed, true);
            app.btnColoDoneRefresh.Icon = '48px-View-refresh.png';
            app.btnColoDoneRefresh.Text = '';
            app.lblReferenceName = uilabel(app.ProcessedchannelsPanel);
            app.lblReferenceName.Text = 'Reference:';
            app.lblColoFishName = uilabel(app.ProcessedchannelsPanel);
            app.lblColoFishName.Text = 'Channel:';
            app.btnColoDouble = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDouble.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoubleButtonPushed, true);
            app.btnColoDouble.Icon = '48px-Fruit-3cherriesCheck.png';
            app.btnColoDouble.Enable = 'off';
            app.btnColoDouble.Text = {'Check Double'; 'Colocalization'};
            app.btnColoDoneUp = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneUp.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneUpButtonPushed, true);
            app.btnColoDoneUp.Icon = '48px-Go-up.png';
            app.btnColoDoneUp.Text = '';
            app.btnColoDoneDown = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneDown.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneDownButtonPushed, true);
            app.btnColoDoneDown.Icon = '48px-Go-down.png';
            app.btnColoDoneDown.Text = '';
            app.lblColo2FishName = uilabel(app.ProcessedchannelsPanel);
            app.lblColo2FishName.Text = 'Channel:';
            app.lblColoc2Rate = uilabel(app.ProcessedchannelsPanel);
            app.lblColoc2Rate.Text = 'Colocalization rate:';
            app.lblColoc2NumObjects = uilabel(app.ProcessedchannelsPanel);
            app.lblColoc2NumObjects.Text = 'Processed:';
            app.lblColocRateDouble = uilabel(app.ProcessedchannelsPanel);
            app.lblColocRateDouble.Text = 'Double colocalized:';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.lblObjectFinder = uilabel(app.AboutTab);
            app.lblObjectFinder.Text = 'ObjectFinder';
            app.lblLicense = uilabel(app.AboutTab);
            app.lblLicense.Text = 'Licensed under GNU GPL version 3.';
            app.lblCopyright = uilabel(app.AboutTab);
            app.lblCopyright.Text = '© 2016-2018  Luca Della Santina';
            app.btnAppIcon = uibutton(app.AboutTab, 'push');
            app.btnAppIcon.Icon = 'app_icon_big.png';
            app.btnAppIcon.Text = '';
            app.lblVersion = uilabel(app.AboutTab);
            app.lblVersion.Text = 'Version 4.10.1';
            app.lblWebsite = uilabel(app.AboutTab);
            app.lblWebsite.Text = 'https://lucadellasantina.github.io/ObjectFinder';
            app.txtLicense = uitextarea(app.AboutTab);
            app.txtLicense.Editable = 'off';
            app.txtLicense.Value = {'GNU GENERAL PUBLIC LICENSE'; 'Version 3, 29 June 2007'; ''; 'Copyright © 2007 Free Software Foundation, Inc. <https://fsf.org/>'; ''; 'Everyone is permitted to copy and distribute verbatim copies of this license document, but changing it is not allowed.'; ''; 'Preamble'; 'The GNU General Public License is a free, copyleft license for software and other kinds of works.'; ''; 'The licenses for most software and other practical works are designed to take away your freedom to share and change the works. By contrast, the GNU General Public License is intended to guarantee your freedom to share and change all versions of a program--to make sure it remains free software for all its users. We, the Free Software Foundation, use the GNU General Public License for most of our software; it applies also to any other work released this way by its authors. You can apply it to your programs, too.'; ''; 'When we speak of free software, we are referring to freedom, not price. Our General Public Licenses are designed to make sure that you have the freedom to distribute copies of free software (and charge for them if you wish), that you receive source code or can get it if you want it, that you can change the software or use pieces of it in new free programs, and that you know you can do these things.'; ''; 'To protect your rights, we need to prevent others from denying you these rights or asking you to surrender the rights. Therefore, you have certain responsibilities if you distribute copies of the software, or if you modify it: responsibilities to respect the freedom of others.'; ''; 'For example, if you distribute copies of such a program, whether gratis or for a fee, you must pass on to the recipients the same freedoms that you received. You must make sure that they, too, receive or can get the source code. And you must show them these terms so they know their rights.'; ''; 'Developers that use the GNU GPL protect your rights with two steps: (1) assert copyright on the software, and (2) offer you this License giving you legal permission to copy, distribute and/or modify it.'; ''; 'For the developers'' and authors'' protection, the GPL clearly explains that there is no warranty for this free software. For both users'' and authors'' sake, the GPL requires that modified versions be marked as changed, so that their problems will not be attributed erroneously to authors of previous versions.'; ''; 'Some devices are designed to deny users access to install or run modified versions of the software inside them, although the manufacturer can do so. This is fundamentally incompatible with the aim of protecting users'' freedom to change the software. The systematic pattern of such abuse occurs in the area of products for individuals to use, which is precisely where it is most unacceptable. Therefore, we have designed this version of the GPL to prohibit the practice for those products. If such problems arise substantially in other domains, we stand ready to extend this provision to those domains in future versions of the GPL, as needed to protect the freedom of users.'; ''; 'Finally, every program is threatened constantly by software patents. States should not allow patents to restrict development and use of software on general-purpose computers, but in those that do, we wish to avoid the special danger that patents applied to a free program could make it effectively proprietary. To prevent this, the GPL assures that patents cannot be used to render the program non-free.'; ''; 'The precise terms and conditions for copying, distribution and modification follow.'; ''; 'TERMS AND CONDITIONS'; '0. Definitions.'; '“This License” refers to version 3 of the GNU General Public License.'; ''; '“Copyright” also means copyright-like laws that apply to other kinds of works, such as semiconductor masks.'; ''; '“The Program” refers to any copyrightable work licensed under this License. Each licensee is addressed as “you”. “Licensees” and “recipients” may be individuals or organizations.'; ''; 'To “modify” a work means to copy from or adapt all or part of the work in a fashion requiring copyright permission, other than the making of an exact copy. The resulting work is called a “modified version” of the earlier work or a work “based on” the earlier work.'; ''; 'A “covered work” means either the unmodified Program or a work based on the Program.'; ''; 'To “propagate” a work means to do anything with it that, without permission, would make you directly or secondarily liable for infringement under applicable copyright law, except executing it on a computer or modifying a private copy. Propagation includes copying, distribution (with or without modification), making available to the public, and in some countries other activities as well.'; ''; 'To “convey” a work means any kind of propagation that enables other parties to make or receive copies. Mere interaction with a user through a computer network, with no transfer of a copy, is not conveying.'; ''; 'An interactive user interface displays “Appropriate Legal Notices” to the extent that it includes a convenient and prominently visible feature that (1) displays an appropriate copyright notice, and (2) tells the user that there is no warranty for the work (except to the extent that warranties are provided), that licensees may convey the work under this License, and how to view a copy of this License. If the interface presents a list of user commands or options, such as a menu, a prominent item in the list meets this criterion.'; ''; '1. Source Code.'; 'The “source code” for a work means the preferred form of the work for making modifications to it. “Object code” means any non-source form of a work.'; ''; 'A “Standard Interface” means an interface that either is an official standard defined by a recognized standards body, or, in the case of interfaces specified for a particular programming language, one that is widely used among developers working in that language.'; ''; 'The “System Libraries” of an executable work include anything, other than the work as a whole, that (a) is included in the normal form of packaging a Major Component, but which is not part of that Major Component, and (b) serves only to enable use of the work with that Major Component, or to implement a Standard Interface for which an implementation is available to the public in source code form. A “Major Component”, in this context, means a major essential component (kernel, window system, and so on) of the specific operating system (if any) on which the executable work runs, or a compiler used to produce the work, or an object code interpreter used to run it.'; ''; 'The “Corresponding Source” for a work in object code form means all the source code needed to generate, install, and (for an executable work) run the object code and to modify the work, including scripts to control those activities. However, it does not include the work''s System Libraries, or general-purpose tools or generally available free programs which are used unmodified in performing those activities but which are not part of the work. For example, Corresponding Source includes interface definition files associated with source files for the work, and the source code for shared libraries and dynamically linked subprograms that the work is specifically designed to require, such as by intimate data communication or control flow between those subprograms and other parts of the work.'; ''; 'The Corresponding Source need not include anything that users can regenerate automatically from other parts of the Corresponding Source.'; ''; 'The Corresponding Source for a work in source code form is that same work.'; ''; '2. Basic Permissions.'; 'All rights granted under this License are granted for the term of copyright on the Program, and are irrevocable provided the stated conditions are met. This License explicitly affirms your unlimited permission to run the unmodified Program. The output from running a covered work is covered by this License only if the output, given its content, constitutes a covered work. This License acknowledges your rights of fair use or other equivalent, as provided by copyright law.'; ''; 'You may make, run and propagate covered works that you do not convey, without conditions so long as your license otherwise remains in force. You may convey covered works to others for the sole purpose of having them make modifications exclusively for you, or provide you with facilities for running those works, provided that you comply with the terms of this License in conveying all material for which you do not control copyright. Those thus making or running the covered works for you must do so exclusively on your behalf, under your direction and control, on terms that prohibit them from making any copies of your copyrighted material outside their relationship with you.'; ''; 'Conveying under any other circumstances is permitted solely under the conditions stated below. Sublicensing is not allowed; section 10 makes it unnecessary.'; ''; '3. Protecting Users'' Legal Rights From Anti-Circumvention Law.'; 'No covered work shall be deemed part of an effective technological measure under any applicable law fulfilling obligations under article 11 of the WIPO copyright treaty adopted on 20 December 1996, or similar laws prohibiting or restricting circumvention of such measures.'; ''; 'When you convey a covered work, you waive any legal power to forbid circumvention of technological measures to the extent such circumvention is effected by exercising rights under this License with respect to the covered work, and you disclaim any intention to limit operation or modification of the work as a means of enforcing, against the work''s users, your or third parties'' legal rights to forbid circumvention of technological measures.'; ''; '4. Conveying Verbatim Copies.'; 'You may convey verbatim copies of the Program''s source code as you receive it, in any medium, provided that you conspicuously and appropriately publish on each copy an appropriate copyright notice; keep intact all notices stating that this License and any non-permissive terms added in accord with section 7 apply to the code; keep intact all notices of the absence of any warranty; and give all recipients a copy of this License along with the Program.'; ''; 'You may charge any price or no price for each copy that you convey, and you may offer support or warranty protection for a fee.'; ''; '5. Conveying Modified Source Versions.'; 'You may convey a work based on the Program, or the modifications to produce it from the Program, in the form of source code under the terms of section 4, provided that you also meet all of these conditions:'; ''; 'a) The work must carry prominent notices stating that you modified it, and giving a relevant date.'; 'b) The work must carry prominent notices stating that it is released under this License and any conditions added under section 7. This requirement modifies the requirement in section 4 to “keep intact all notices”.'; 'c) You must license the entire work, as a whole, under this License to anyone who comes into possession of a copy. This License will therefore apply, along with any applicable section 7 additional terms, to the whole of the work, and all its parts, regardless of how they are packaged. This License gives no permission to license the work in any other way, but it does not invalidate such permission if you have separately received it.'; 'd) If the work has interactive user interfaces, each must display Appropriate Legal Notices; however, if the Program has interactive interfaces that do not display Appropriate Legal Notices, your work need not make them do so.'; 'A compilation of a covered work with other separate and independent works, which are not by their nature extensions of the covered work, and which are not combined with it such as to form a larger program, in or on a volume of a storage or distribution medium, is called an “aggregate” if the compilation and its resulting copyright are not used to limit the access or legal rights of the compilation''s users beyond what the individual works permit. Inclusion of a covered work in an aggregate does not cause this License to apply to the other parts of the aggregate.'; ''; '6. Conveying Non-Source Forms.'; 'You may convey a covered work in object code form under the terms of sections 4 and 5, provided that you also convey the machine-readable Corresponding Source under the terms of this License, in one of these ways:'; ''; 'a) Convey the object code in, or embodied in, a physical product (including a physical distribution medium), accompanied by the Corresponding Source fixed on a durable physical medium customarily used for software interchange.'; 'b) Convey the object code in, or embodied in, a physical product (including a physical distribution medium), accompanied by a written offer, valid for at least three years and valid for as long as you offer spare parts or customer support for that product model, to give anyone who possesses the object code either (1) a copy of the Corresponding Source for all the software in the product that is covered by this License, on a durable physical medium customarily used for software interchange, for a price no more than your reasonable cost of physically performing this conveying of source, or (2) access to copy the Corresponding Source from a network server at no charge.'; 'c) Convey individual copies of the object code with a copy of the written offer to provide the Corresponding Source. This alternative is allowed only occasionally and noncommercially, and only if you received the object code with such an offer, in accord with subsection 6b.'; 'd) Convey the object code by offering access from a designated place (gratis or for a charge), and offer equivalent access to the Corresponding Source in the same way through the same place at no further charge. You need not require recipients to copy the Corresponding Source along with the object code. If the place to copy the object code is a network server, the Corresponding Source may be on a different server (operated by you or a third party) that supports equivalent copying facilities, provided you maintain clear directions next to the object code saying where to find the Corresponding Source. Regardless of what server hosts the Corresponding Source, you remain obligated to ensure that it is available for as long as needed to satisfy these requirements.'; 'e) Convey the object code using peer-to-peer transmission, provided you inform other peers where the object code and Corresponding Source of the work are being offered to the general public at no charge under subsection 6d.'; 'A separable portion of the object code, whose source code is excluded from the Corresponding Source as a System Library, need not be included in conveying the object code work.'; ''; 'A “User Product” is either (1) a “consumer product”, which means any tangible personal property which is normally used for personal, family, or household purposes, or (2) anything designed or sold for incorporation into a dwelling. In determining whether a product is a consumer product, doubtful cases shall be resolved in favor of coverage. For a particular product received by a particular user, “normally used” refers to a typical or common use of that class of product, regardless of the status of the particular user or of the way in which the particular user actually uses, or expects or is expected to use, the product. A product is a consumer product regardless of whether the product has substantial commercial, industrial or non-consumer uses, unless such uses represent the only significant mode of use of the product.'; ''; '“Installation Information” for a User Product means any methods, procedures, authorization keys, or other information required to install and execute modified versions of a covered work in that User Product from a modified version of its Corresponding Source. The information must suffice to ensure that the continued functioning of the modified object code is in no case prevented or interfered with solely because modification has been made.'; ''; 'If you convey an object code work under this section in, or with, or specifically for use in, a User Product, and the conveying occurs as part of a transaction in which the right of possession and use of the User Product is transferred to the recipient in perpetuity or for a fixed term (regardless of how the transaction is characterized), the Corresponding Source conveyed under this section must be accompanied by the Installation Information. But this requirement does not apply if neither you nor any third party retains the ability to install modified object code on the User Product (for example, the work has been installed in ROM).'; ''; 'The requirement to provide Installation Information does not include a requirement to continue to provide support service, warranty, or updates for a work that has been modified or installed by the recipient, or for the User Product in which it has been modified or installed. Access to a network may be denied when the modification itself materially and adversely affects the operation of the network or violates the rules and protocols for communication across the network.'; ''; 'Corresponding Source conveyed, and Installation Information provided, in accord with this section must be in a format that is publicly documented (and with an implementation available to the public in source code form), and must require no special password or key for unpacking, reading or copying.'; ''; '7. Additional Terms.'; '“Additional permissions” are terms that supplement the terms of this License by making exceptions from one or more of its conditions. Additional permissions that are applicable to the entire Program shall be treated as though they were included in this License, to the extent that they are valid under applicable law. If additional permissions apply only to part of the Program, that part may be used separately under those permissions, but the entire Program remains governed by this License without regard to the additional permissions.'; ''; 'When you convey a copy of a covered work, you may at your option remove any additional permissions from that copy, or from any part of it. (Additional permissions may be written to require their own removal in certain cases when you modify the work.) You may place additional permissions on material, added by you to a covered work, for which you have or can give appropriate copyright permission.'; ''; 'Notwithstanding any other provision of this License, for material you add to a covered work, you may (if authorized by the copyright holders of that material) supplement the terms of this License with terms:'; ''; 'a) Disclaiming warranty or limiting liability differently from the terms of sections 15 and 16 of this License; or'; 'b) Requiring preservation of specified reasonable legal notices or author attributions in that material or in the Appropriate Legal Notices displayed by works containing it; or'; 'c) Prohibiting misrepresentation of the origin of that material, or requiring that modified versions of such material be marked in reasonable ways as different from the original version; or'; 'd) Limiting the use for publicity purposes of names of licensors or authors of the material; or'; 'e) Declining to grant rights under trademark law for use of some trade names, trademarks, or service marks; or'; 'f) Requiring indemnification of licensors and authors of that material by anyone who conveys the material (or modified versions of it) with contractual assumptions of liability to the recipient, for any liability that these contractual assumptions directly impose on those licensors and authors.'; 'All other non-permissive additional terms are considered “further restrictions” within the meaning of section 10. If the Program as you received it, or any part of it, contains a notice stating that it is governed by this License along with a term that is a further restriction, you may remove that term. If a license document contains a further restriction but permits relicensing or conveying under this License, you may add to a covered work material governed by the terms of that license document, provided that the further restriction does not survive such relicensing or conveying.'; ''; 'If you add terms to a covered work in accord with this section, you must place, in the relevant source files, a statement of the additional terms that apply to those files, or a notice indicating where to find the applicable terms.'; ''; 'Additional terms, permissive or non-permissive, may be stated in the form of a separately written license, or stated as exceptions; the above requirements apply either way.'; ''; '8. Termination.'; 'You may not propagate or modify a covered work except as expressly provided under this License. Any attempt otherwise to propagate or modify it is void, and will automatically terminate your rights under this License (including any patent licenses granted under the third paragraph of section 11).'; ''; 'However, if you cease all violation of this License, then your license from a particular copyright holder is reinstated (a) provisionally, unless and until the copyright holder explicitly and finally terminates your license, and (b) permanently, if the copyright holder fails to notify you of the violation by some reasonable means prior to 60 days after the cessation.'; ''; 'Moreover, your license from a particular copyright holder is reinstated permanently if the copyright holder notifies you of the violation by some reasonable means, this is the first time you have received notice of violation of this License (for any work) from that copyright holder, and you cure the violation prior to 30 days after your receipt of the notice.'; ''; 'Termination of your rights under this section does not terminate the licenses of parties who have received copies or rights from you under this License. If your rights have been terminated and not permanently reinstated, you do not qualify to receive new licenses for the same material under section 10.'; ''; '9. Acceptance Not Required for Having Copies.'; 'You are not required to accept this License in order to receive or run a copy of the Program. Ancillary propagation of a covered work occurring solely as a consequence of using peer-to-peer transmission to receive a copy likewise does not require acceptance. However, nothing other than this License grants you permission to propagate or modify any covered work. These actions infringe copyright if you do not accept this License. Therefore, by modifying or propagating a covered work, you indicate your acceptance of this License to do so.'; ''; '10. Automatic Licensing of Downstream Recipients.'; 'Each time you convey a covered work, the recipient automatically receives a license from the original licensors, to run, modify and propagate that work, subject to this License. You are not responsible for enforcing compliance by third parties with this License.'; ''; 'An “entity transaction” is a transaction transferring control of an organization, or substantially all assets of one, or subdividing an organization, or merging organizations. If propagation of a covered work results from an entity transaction, each party to that transaction who receives a copy of the work also receives whatever licenses to the work the party''s predecessor in interest had or could give under the previous paragraph, plus a right to possession of the Corresponding Source of the work from the predecessor in interest, if the predecessor has it or can get it with reasonable efforts.'; ''; 'You may not impose any further restrictions on the exercise of the rights granted or affirmed under this License. For example, you may not impose a license fee, royalty, or other charge for exercise of rights granted under this License, and you may not initiate litigation (including a cross-claim or counterclaim in a lawsuit) alleging that any patent claim is infringed by making, using, selling, offering for sale, or importing the Program or any portion of it.'; ''; '11. Patents.'; 'A “contributor” is a copyright holder who authorizes use under this License of the Program or a work on which the Program is based. The work thus licensed is called the contributor''s “contributor version”.'; ''; 'A contributor''s “essential patent claims” are all patent claims owned or controlled by the contributor, whether already acquired or hereafter acquired, that would be infringed by some manner, permitted by this License, of making, using, or selling its contributor version, but do not include claims that would be infringed only as a consequence of further modification of the contributor version. For purposes of this definition, “control” includes the right to grant patent sublicenses in a manner consistent with the requirements of this License.'; ''; 'Each contributor grants you a non-exclusive, worldwide, royalty-free patent license under the contributor''s essential patent claims, to make, use, sell, offer for sale, import and otherwise run, modify and propagate the contents of its contributor version.'; ''; 'In the following three paragraphs, a “patent license” is any express agreement or commitment, however denominated, not to enforce a patent (such as an express permission to practice a patent or covenant not to sue for patent infringement). To “grant” such a patent license to a party means to make such an agreement or commitment not to enforce a patent against the party.'; ''; 'If you convey a covered work, knowingly relying on a patent license, and the Corresponding Source of the work is not available for anyone to copy, free of charge and under the terms of this License, through a publicly available network server or other readily accessible means, then you must either (1) cause the Corresponding Source to be so available, or (2) arrange to deprive yourself of the benefit of the patent license for this particular work, or (3) arrange, in a manner consistent with the requirements of this License, to extend the patent license to downstream recipients. “Knowingly relying” means you have actual knowledge that, but for the patent license, your conveying the covered work in a country, or your recipient''s use of the covered work in a country, would infringe one or more identifiable patents in that country that you have reason to believe are valid.'; ''; 'If, pursuant to or in connection with a single transaction or arrangement, you convey, or propagate by procuring conveyance of, a covered work, and grant a patent license to some of the parties receiving the covered work authorizing them to use, propagate, modify or convey a specific copy of the covered work, then the patent license you grant is automatically extended to all recipients of the covered work and works based on it.'; ''; 'A patent license is “discriminatory” if it does not include within the scope of its coverage, prohibits the exercise of, or is conditioned on the non-exercise of one or more of the rights that are specifically granted under this License. You may not convey a covered work if you are a party to an arrangement with a third party that is in the business of distributing software, under which you make payment to the third party based on the extent of your activity of conveying the work, and under which the third party grants, to any of the parties who would receive the covered work from you, a discriminatory patent license (a) in connection with copies of the covered work conveyed by you (or copies made from those copies), or (b) primarily for and in connection with specific products or compilations that contain the covered work, unless you entered into that arrangement, or that patent license was granted, prior to 28 March 2007.'; ''; 'Nothing in this License shall be construed as excluding or limiting any implied license or other defenses to infringement that may otherwise be available to you under applicable patent law.'; ''; '12. No Surrender of Others'' Freedom.'; 'If conditions are imposed on you (whether by court order, agreement or otherwise) that contradict the conditions of this License, they do not excuse you from the conditions of this License. If you cannot convey a covered work so as to satisfy simultaneously your obligations under this License and any other pertinent obligations, then as a consequence you may not convey it at all. For example, if you agree to terms that obligate you to collect a royalty for further conveying from those to whom you convey the Program, the only way you could satisfy both those terms and this License would be to refrain entirely from conveying the Program.'; ''; '13. Use with the GNU Affero General Public License.'; 'Notwithstanding any other provision of this License, you have permission to link or combine any covered work with a work licensed under version 3 of the GNU Affero General Public License into a single combined work, and to convey the resulting work. The terms of this License will continue to apply to the part which is the covered work, but the special requirements of the GNU Affero General Public License, section 13, concerning interaction through a network will apply to the combination as such.'; ''; '14. Revised Versions of this License.'; 'The Free Software Foundation may publish revised and/or new versions of the GNU General Public License from time to time. Such new versions will be similar in spirit to the present version, but may differ in detail to address new problems or concerns.'; ''; 'Each version is given a distinguishing version number. If the Program specifies that a certain numbered version of the GNU General Public License “or any later version” applies to it, you have the option of following the terms and conditions either of that numbered version or of any later version published by the Free Software Foundation. If the Program does not specify a version number of the GNU General Public License, you may choose any version ever published by the Free Software Foundation.'; ''; 'If the Program specifies that a proxy can decide which future versions of the GNU General Public License can be used, that proxy''s public statement of acceptance of a version permanently authorizes you to choose that version for the Program.'; ''; 'Later license versions may give you additional or different permissions. However, no additional obligations are imposed on any author or copyright holder as a result of your choosing to follow a later version.'; ''; '15. Disclaimer of Warranty.'; 'THERE IS NO WARRANTY FOR THE PROGRAM, TO THE EXTENT PERMITTED BY APPLICABLE LAW. EXCEPT WHEN OTHERWISE STATED IN WRITING THE COPYRIGHT HOLDERS AND/OR OTHER PARTIES PROVIDE THE PROGRAM “AS IS” WITHOUT WARRANTY OF ANY KIND, EITHER EXPRESSED OR IMPLIED, INCLUDING, BUT NOT LIMITED TO, THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE. THE ENTIRE RISK AS TO THE QUALITY AND PERFORMANCE OF THE PROGRAM IS WITH YOU. SHOULD THE PROGRAM PROVE DEFECTIVE, YOU ASSUME THE COST OF ALL NECESSARY SERVICING, REPAIR OR CORRECTION.'; ''; '16. Limitation of Liability.'; 'IN NO EVENT UNLESS REQUIRED BY APPLICABLE LAW OR AGREED TO IN WRITING WILL ANY COPYRIGHT HOLDER, OR ANY OTHER PARTY WHO MODIFIES AND/OR CONVEYS THE PROGRAM AS PERMITTED ABOVE, BE LIABLE TO YOU FOR DAMAGES, INCLUDING ANY GENERAL, SPECIAL, INCIDENTAL OR CONSEQUENTIAL DAMAGES ARISING OUT OF THE USE OR INABILITY TO USE THE PROGRAM (INCLUDING BUT NOT LIMITED TO LOSS OF DATA OR DATA BEING RENDERED INACCURATE OR LOSSES SUSTAINED BY YOU OR THIRD PARTIES OR A FAILURE OF THE PROGRAM TO OPERATE WITH ANY OTHER PROGRAMS), EVEN IF SUCH HOLDER OR OTHER PARTY HAS BEEN ADVISED OF THE POSSIBILITY OF SUCH DAMAGES.'; ''; '17. Interpretation of Sections 15 and 16.'; 'If the disclaimer of warranty and limitation of liability provided above cannot be given local legal effect according to their terms, reviewing courts shall apply local law that most closely approximates an absolute waiver of all civil liability in connection with the Program, unless a warranty or assumption of liability accompanies a copy of the Program in return for a fee.'; ''; 'END OF TERMS AND CONDITIONS'; ''; 'How to Apply These Terms to Your New Programs'; 'If you develop a new program, and you want it to be of the greatest possible use to the public, the best way to achieve this is to make it free software which everyone can redistribute and change under these terms.'; ''; 'To do so, attach the following notices to the program. It is safest to attach them to the start of each source file to most effectively state the exclusion of warranty; and each file should have at least the “copyright” line and a pointer to where the full notice is found.'; ''; '    <one line to give the program''s name and a brief idea of what it does.>'; '    Copyright (C) <year>  <name of author>'; ''; '    This program is free software: you can redistribute it and/or modify'; '    it under the terms of the GNU General Public License as published by'; '    the Free Software Foundation, either version 3 of the License, or'; '    (at your option) any later version.'; ''; '    This program is distributed in the hope that it will be useful,'; '    but WITHOUT ANY WARRANTY; without even the implied warranty of'; '    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the'; '    GNU General Public License for more details.'; ''; '    You should have received a copy of the GNU General Public License'; '    along with this program.  If not, see <https://www.gnu.org/licenses/>.'; 'Also add information on how to contact you by electronic and paper mail.'; ''; 'If the program does terminal interaction, make it output a short notice like this when it starts in an interactive mode:'; ''; '    <program>  Copyright (C) <year>  <name of author>'; '    This program comes with ABSOLUTELY NO WARRANTY; for details type `show w''.'; '    This is free software, and you are welcome to redistribute it'; '    under certain conditions; type `show c'' for details.'; 'The hypothetical commands `show w'' and `show c'' should show the appropriate parts of the General Public License. Of course, your program''s commands might be different; for a GUI interface, you would use an “about box”.'; ''; 'You should also get your employer (if you work as a programmer) or school, if any, to sign a “copyright disclaimer” for the program, if necessary. For more information on this, and how to apply and follow the GNU GPL, see <https://www.gnu.org/licenses/>.'; ''; 'The GNU General Public License does not permit incorporating your program into proprietary programs. If your program is a subroutine library, you may consider it more useful to permit linking proprietary applications with the library. If this is what you want to do, use the GNU Lesser General Public License instead of this License. But first, please read <https://www.gnu.org/licenses/why-not-lgpl.html>.'};
            app.FolderField = uieditfield(app.UIFigure, 'text');
            app.FolderField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ObjectFinder

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end